classdef stimulus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SearchTab                      matlab.ui.container.Tab
        ControlPanel                   matlab.ui.container.Panel
        CarrierFrequenciesHzPanel      matlab.ui.container.Panel
        Fc_check_1                     matlab.ui.control.CheckBox
        Fc_1                           matlab.ui.control.NumericEditField
        Fc_2                           matlab.ui.control.NumericEditField
        Fc_check_2                     matlab.ui.control.CheckBox
        Fc_3                           matlab.ui.control.NumericEditField
        Fc_check_3                     matlab.ui.control.CheckBox
        Fc_4                           matlab.ui.control.NumericEditField
        Fc_check_4                     matlab.ui.control.CheckBox
        Fc_5                           matlab.ui.control.NumericEditField
        Fc_check_5                     matlab.ui.control.CheckBox
        ModulatorFrequenciesHzPanel    matlab.ui.container.Panel
        Fm_check_1                     matlab.ui.control.CheckBox
        Fm_1                           matlab.ui.control.NumericEditField
        Fm_2                           matlab.ui.control.NumericEditField
        Fm_check_2                     matlab.ui.control.CheckBox
        Fm_3                           matlab.ui.control.NumericEditField
        Fm_check_3                     matlab.ui.control.CheckBox
        Fm_4                           matlab.ui.control.NumericEditField
        Fm_check_4                     matlab.ui.control.CheckBox
        Fm_5                           matlab.ui.control.NumericEditField
        Fm_check_5                     matlab.ui.control.CheckBox
        ModeDropDownLabel              matlab.ui.control.Label
        ModeDropDown                   matlab.ui.control.DropDown
        FrequencyCyclerHzPanel         matlab.ui.container.Panel
        StartEditFieldLabel            matlab.ui.control.Label
        Start_frequency                matlab.ui.control.NumericEditField
        StepEditFieldLabel             matlab.ui.control.Label
        Step_frequency                 matlab.ui.control.NumericEditField
        EndEditFieldLabel              matlab.ui.control.Label
        End_frequency                  matlab.ui.control.NumericEditField
        StimulusDurationmsecLabel      matlab.ui.control.Label
        Search_stimulus_duration       matlab.ui.control.Spinner
        PRRTab                         matlab.ui.container.Tab
        ControlPanel_PRR               matlab.ui.container.Panel
        CarrierFrequenciesHzPanel_2    matlab.ui.container.Panel
        Fc_PRR_check_1                 matlab.ui.control.CheckBox
        Fc_PRR_1                       matlab.ui.control.NumericEditField
        Fc_PRR_2                       matlab.ui.control.NumericEditField
        Fc_PRR_check_2                 matlab.ui.control.CheckBox
        Fc_PRR_3                       matlab.ui.control.NumericEditField
        Fc_PRR_check_3                 matlab.ui.control.CheckBox
        Fc_PRR_4                       matlab.ui.control.NumericEditField
        Fc_PRR_check_4                 matlab.ui.control.CheckBox
        Fc_PRR_5                       matlab.ui.control.NumericEditField
        Fc_PRR_check_5                 matlab.ui.control.CheckBox
        EnergyPanel                    matlab.ui.container.Panel
        Ec_PRR_1                       matlab.ui.control.NumericEditField
        Ec_PRR_2                       matlab.ui.control.NumericEditField
        Ec_PRR_3                       matlab.ui.control.NumericEditField
        Ec_PRR_4                       matlab.ui.control.NumericEditField
        Ec_PRR_5                       matlab.ui.control.NumericEditField
        PRRppsSelPanel                 matlab.ui.container.Panel
        Fm_PRR_check_1                 matlab.ui.control.CheckBox
        Fm_PRR_1                       matlab.ui.control.NumericEditField
        Fm_PRR_2                       matlab.ui.control.NumericEditField
        Fm_PRR_check_2                 matlab.ui.control.CheckBox
        Fm_PRR_3                       matlab.ui.control.NumericEditField
        Fm_PRR_check_3                 matlab.ui.control.CheckBox
        Fm_PRR_4                       matlab.ui.control.NumericEditField
        Fm_PRR_check_4                 matlab.ui.control.CheckBox
        Fm_PRR_5                       matlab.ui.control.NumericEditField
        Fm_PRR_check_5                 matlab.ui.control.CheckBox
        PRRModeDropDownLabel           matlab.ui.control.Label
        PRR_mode                       matlab.ui.control.DropDown
        PRRCyclerppsCyclePanel         matlab.ui.container.Panel
        StartEditFieldLabel_2          matlab.ui.control.Label
        Fm_PRR_start                   matlab.ui.control.NumericEditField
        StepEditFieldLabel_2           matlab.ui.control.Label
        Fm_PRR_step                    matlab.ui.control.NumericEditField
        EndEditFieldLabel_2            matlab.ui.control.Label
        Fm_PRR_end                     matlab.ui.control.NumericEditField
        EnvelopeDropDownLabel          matlab.ui.control.Label
        PRREnvelope                    matlab.ui.control.DropDown
        EastPanel                      matlab.ui.container.Panel
        nPulsesLabel                   matlab.ui.control.Label
        East_PRR_nPulses               matlab.ui.control.NumericEditField
        PDLabel                        matlab.ui.control.Label
        East_PRR_PD                    matlab.ui.control.NumericEditField
        East_PRR_PD_units              matlab.ui.control.DropDown
        East_PRR_RT_units              matlab.ui.control.DropDown
        RTLabel                        matlab.ui.control.Label
        East_PRR_RT                    matlab.ui.control.NumericEditField
        PulserateppsLabel              matlab.ui.control.Label
        East_PRR_Pulse_rate            matlab.ui.control.NumericEditField
        GapafterpulseLabel             matlab.ui.control.Label
        East_PRR_Gap_after_pulse       matlab.ui.control.NumericEditField
        GaptimemsecLabel               matlab.ui.control.Label
        East_PRR_Gap_time              matlab.ui.control.NumericEditField
        East_PRR_nPulses_check         matlab.ui.control.CheckBox
        WestPanel                      matlab.ui.container.Panel
        nPulsesLabel_2                 matlab.ui.control.Label
        West_PRR_nPulses               matlab.ui.control.NumericEditField
        PDLabel_2                      matlab.ui.control.Label
        West_PRR_PD                    matlab.ui.control.NumericEditField
        West_PRR_PD_units              matlab.ui.control.DropDown
        West_PRR_RT_units              matlab.ui.control.DropDown
        RTLabel_2                      matlab.ui.control.Label
        West_PRR_RT                    matlab.ui.control.NumericEditField
        PulserateppsLabel_2            matlab.ui.control.Label
        West_PRR_Pulse_rate            matlab.ui.control.NumericEditField
        GapafterpulseLabel_2           matlab.ui.control.Label
        West_PRR_Gap_after_pulse       matlab.ui.control.NumericEditField
        GaptimemsecLabel_2             matlab.ui.control.Label
        West_PRR_Gap_time              matlab.ui.control.NumericEditField
        West_PRR_nPulses_check         matlab.ui.control.CheckBox
        NaturalTab                     matlab.ui.container.Tab
        ControlPanel_Natural           matlab.ui.container.Panel
        PlayModeLabel                  matlab.ui.control.Label
        NaturalPlayMode                matlab.ui.control.DropDown
        CallDropDownLabel              matlab.ui.control.Label
        NaturalCall                    matlab.ui.control.DropDown
        SeminaturalTab                 matlab.ui.container.Tab
        CallDropDownLabel_2            matlab.ui.control.Label
        NaturalCall_2                  matlab.ui.control.DropDown
        ControlPanel_2                 matlab.ui.container.Panel
        PRRppsSelPanel_2               matlab.ui.container.Panel
        Fm_PRR_check_6                 matlab.ui.control.CheckBox
        Fm_PRR_6                       matlab.ui.control.NumericEditField
        Fm_PRR_7                       matlab.ui.control.NumericEditField
        Fm_PRR_check_7                 matlab.ui.control.CheckBox
        Fm_PRR_8                       matlab.ui.control.NumericEditField
        Fm_PRR_check_8                 matlab.ui.control.CheckBox
        Fm_PRR_9                       matlab.ui.control.NumericEditField
        Fm_PRR_check_9                 matlab.ui.control.CheckBox
        Fm_PRR_10                      matlab.ui.control.NumericEditField
        Fm_PRR_check_10                matlab.ui.control.CheckBox
        PlayModeLabel_2                matlab.ui.control.Label
        NaturalPlayMode_2              matlab.ui.control.DropDown
        Speaker                        matlab.ui.control.DiscreteKnob
        ControlSwitch                  matlab.ui.control.ToggleSwitch
        StatusPanel                    matlab.ui.container.Panel
        ConnectionLampLabel            matlab.ui.control.Label
        ConnectionLamp                 matlab.ui.control.Lamp
        LoadCircuitLampLabel           matlab.ui.control.Label
        LoadCircuitLamp                matlab.ui.control.Lamp
        LoadParametersLampLabel        matlab.ui.control.Label
        LoadParametersLamp             matlab.ui.control.Lamp
        WestSpeakerPanel               matlab.ui.container.Panel
        West_plot_type                 matlab.ui.control.RockerSwitch
        West_current_carrier           matlab.ui.control.NumericEditField
        West_current_modulator         matlab.ui.control.NumericEditField
        West_modulator_plot            matlab.ui.control.UIAxes
        West_carrier_plot              matlab.ui.control.UIAxes
        West_stimulus_plot             matlab.ui.control.UIAxes
        EastSpeakerPanel               matlab.ui.container.Panel
        East_plot_type                 matlab.ui.control.RockerSwitch
        East_current_carrier           matlab.ui.control.NumericEditField
        East_current_modulator         matlab.ui.control.NumericEditField
        East_modulator_plot            matlab.ui.control.UIAxes
        East_carrier_plot              matlab.ui.control.UIAxes
        East_stimulus_plot             matlab.ui.control.UIAxes
        InterStimulusIntervalsecLabel  matlab.ui.control.Label
        ISI                            matlab.ui.control.Spinner
        InterSpeakerIntervalmsecLabel  matlab.ui.control.Label
        Inter_speaker_interval         matlab.ui.control.Spinner
        SpeciesDropDownLabel           matlab.ui.control.Label
        SpeciesDropDown                matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        RPcontroller % actxcontrol of RP2.1 processor.s
        sampling_rate = 24144.0625; % Hz. Sampling rate of RP2.1 processor.
        debug = false;
        calls_list;
        calls;
    end
    
    methods (Access = private)

        function [n, signal_fft] = compute_fft(~, signal, time)
            samples = size(time, 2);
            n = 2^nextpow2(samples);
            signal_fft = fft(signal, n, 2);
            signal_fft = abs(signal_fft/samples);
            signal_fft = signal_fft(:, 1:n/2+1);
            signal_fft(:, 2:end-1) = 2*signal_fft(:, 2:end-1);
        end
        
        function hardware_initialization(app)
            app.RPcontroller = actxserver('RPco.x');
            if app.RPcontroller.ConnectRP2('GB', 1)
                app.ConnectionLamp.Color = [0, 1, 0];
            else
                app.ConnectionLamp.Color = [1, 0, 0];
            end
        end
        
        function e = load_circuit(app, circuit)
            e = app.RPcontroller.LoadCOF(circuit);
            if e
                app.LoadCircuitLamp.Color = [0, 1, 0];
            else
                app.LoadCircuitLamp.Color = [1, 0, 0];
            end
        end

        %% Search Tab Functions
        function search_mode_normal(app)
            % Search Tab normal mode configuration.
            app.Fc_check_1.Value = 1;
            app.Fc_check_2.Value = 1;
            app.Fc_check_3.Value = 1;
            app.Fc_check_4.Value = 1;
            app.Fc_check_5.Value = 1;
            app.Fc_check_1ValueChanged();
            app.Fc_check_2ValueChanged();
            app.Fc_check_3ValueChanged();
            app.Fc_check_4ValueChanged();
            app.Fc_check_5ValueChanged();
            app.Fm_check_1.Value = 1;
            app.Fm_check_2.Value = 1;
            app.Fm_check_3.Value = 1;
            app.Fm_check_4.Value = 1;
            app.Fm_check_5.Value = 1;
            app.Fm_check_1ValueChanged();
            app.Fm_check_2ValueChanged();
            app.Fm_check_3ValueChanged();
            app.Fm_check_4ValueChanged();
            app.Fm_check_5ValueChanged();
            app.Start_frequency.Enable = 0;
            app.Step_frequency.Enable = 0;
            app.End_frequency.Enable = 0;
        end
        
        function search_mode_fixed_carrier(app)
            % Fixed carrier configuration.
            app.Fc_check_1.Value = 0;
            app.Fc_check_2.Value = 0;
            app.Fc_check_3.Value = 0;
            app.Fc_check_4.Value = 0;
            app.Fc_check_5.Value = 1;
            app.Fc_check_1ValueChanged();
            app.Fc_check_2ValueChanged();
            app.Fc_check_3ValueChanged();
            app.Fc_check_4ValueChanged();
            app.Fc_check_5ValueChanged();
            app.Fm_check_1.Value = 0;
            app.Fm_check_2.Value = 0;
            app.Fm_check_3.Value = 0;
            app.Fm_check_4.Value = 0;
            app.Fm_check_5.Value = 0;
            app.Fm_check_1ValueChanged();
            app.Fm_check_2ValueChanged();
            app.Fm_check_3ValueChanged();
            app.Fm_check_4ValueChanged();
            app.Fm_check_5ValueChanged();
            app.Start_frequency.Enable = 1;
            app.Start_frequency.Value = 10;
            app.Step_frequency.Enable = 1;
            app.Step_frequency.Value = 10;
            app.End_frequency.Enable = 1;
            app.End_frequency.Value = 80;
        end
        
        function search_mode_fixed_modulation(app)
            % Fixed modulation configuration.
            app.Fc_check_1.Value = 0;
            app.Fc_check_2.Value = 0;
            app.Fc_check_3.Value = 0;
            app.Fc_check_4.Value = 0;
            app.Fc_check_5.Value = 0;
            app.Fc_check_1ValueChanged();
            app.Fc_check_2ValueChanged();
            app.Fc_check_3ValueChanged();
            app.Fc_check_4ValueChanged();
            app.Fc_check_5ValueChanged();
            app.Fm_check_1.Value = 0;
            app.Fm_check_2.Value = 0;
            app.Fm_check_3.Value = 0;
            app.Fm_check_4.Value = 0;
            app.Fm_check_5.Value = 1;
            app.Fm_check_1ValueChanged();
            app.Fm_check_2ValueChanged();
            app.Fm_check_3ValueChanged();
            app.Fm_check_4ValueChanged();
            app.Fm_check_5ValueChanged();
            app.Start_frequency.Enable = 1;
            app.Start_frequency.Value = 300;
            app.Step_frequency.Enable = 1;
            app.Step_frequency.Value = 20;
            app.End_frequency.Enable = 1;
            app.End_frequency.Value = 600;
        end
        
        function freqs = search_get_carriers(app)
            % Collect active carrier frequencies.
            mode = app.ModeDropDown.Value;
            if mode == "Fixed Modulation"
                freqs = app.Start_frequency.Value: app.Step_frequency.Value: app.End_frequency.Value;
            else
                freqs = [];
                if app.Fc_check_1.Value == 1
                    freqs(end+1) = app.Fc_1.Value;
                end
                if app.Fc_check_2.Value == 1
                    freqs(end+1) = app.Fc_2.Value;
                end
                if app.Fc_check_3.Value == 1
                    freqs(end+1) = app.Fc_3.Value;
                end
                if app.Fc_check_4.Value == 1
                    freqs(end+1) = app.Fc_4.Value;
                end
                if app.Fc_check_5.Value == 1
                    freqs(end+1) = app.Fc_5.Value;
                end
            end
        end
        
        function freqs = search_get_modulators(app)
            % Collect modulation frequencies.
            mode = app.ModeDropDown.Value;
            if mode == "Fixed Carrier"
                freqs = app.Start_frequency.Value: app.Step_frequency.Value: app.End_frequency.Value;
            else
                freqs = [];
                if app.Fm_check_1.Value == 1
                    freqs(end+1) = app.Fm_1.Value;
                end
                if app.Fm_check_2.Value == 1
                    freqs(end+1) = app.Fm_2.Value;
                end
                if app.Fm_check_3.Value == 1
                    freqs(end+1) = app.Fm_3.Value;
                end
                if app.Fm_check_4.Value == 1
                    freqs(end+1) = app.Fm_4.Value;
                end
                if app.Fm_check_5.Value == 1
                    freqs(end+1) = app.Fm_5.Value;
                end
            end
        end
        
        function [time, carrier, modulator, stimulus, stimulus_trigger, pulse_trigger] = build_AM(app, carrier_frequency, modulator_frequency, append_time, region)
            % Generate Search Stimulus.
            stimulus_duration = app.Search_stimulus_duration.Value*1e-3; % Inter-stimulus interval. Converted from msec to sec.
            append_samples = floor(app.sampling_rate*append_time);
            time = 0: (1/app.sampling_rate) : (stimulus_duration - (1/app.sampling_rate)); % Array of time samples.
            stimulus_trigger = zeros(size(time));
            stimulus_trigger(1:end, 1) = 1;
            pulse_trigger = zeros(size(time));
            pulse_trigger(1:end, 1) = 1;
            if region == "start"
                carrier = cat(2, zeros(1, append_samples), sin(2*pi*carrier_frequency.*time)); % Generate carrier sinusoid for all carrier frequencies.
                modulator = cat(2, zeros(1, append_samples), sin(2*pi*modulator_frequency.*time - pi/2)); % Generate modulator sinusoid for all modulator frequencies.
                stimulus_trigger = cat(2, zeros(1, append_samples), stimulus_trigger);
                pulse_trigger = cat(2, zeros(1, append_samples), pulse_trigger);
            elseif region == "end"
                carrier = cat(2, sin(2*pi*carrier_frequency.*time), zeros(1, append_samples));
                modulator = cat(2, sin(2*pi*modulator_frequency.*time - pi/2), zeros(1, append_samples)); % The correct modulator is sin(2*pi*fm*t + pi/2) + 1; 1 is added later in the code.
                stimulus_trigger = cat(2, stimulus_trigger, zeros(1, append_samples));
                pulse_trigger = cat(2, pulse_trigger, zeros(1, append_samples));
            end
            modulator = 0.5*modulator + 0.5; % adding 1
            stimulus = 10.*carrier .* modulator; % Generate stimuli.
            time = 0: (1/app.sampling_rate): ((size(stimulus, 2)-1)/app.sampling_rate);
        end
        
        function [time, carrier, modulator, stimulus, stimulus_trigger, pulse_trigger] = build_biaural_search_stimulus(app, carrier_frequency, modulator_frequency)
            speaker_interval = app.Inter_speaker_interval.Value*1e-3;
            if speaker_interval >= 0
                [West.time, West.carrier, West.modulator, West.stimulus, West.stimulus_trigger, West.pulse_trigger] = app.build_AM(carrier_frequency, modulator_frequency, abs(speaker_interval), "end");
                [East.time, East.carrier, East.modulator, East.stimulus, East.stimulus_trigger, East.pulse_trigger] = app.build_AM(carrier_frequency, modulator_frequency, abs(speaker_interval), "start");
            elseif speaker_interval < 0
                [West.time, West.carrier, West.modulator, West.stimulus, West.stimulus_trigger, West.pulse_trigger] = app.build_AM(carrier_frequency, modulator_frequency, abs(speaker_interval), "start");
                [East.time, East.carrier, East.modulator, East.stimulus, East.stimulus_trigger, East.pulse_trigger] = app.build_AM(carrier_frequency, modulator_frequency, abs(speaker_interval), "end");
            end
            if app.Speaker.Value == "East"
                app.East_current_carrier.Value = carrier_frequency;
                app.East_current_modulator.Value = modulator_frequency;
                app.West_current_carrier.Value = 0;
                app.West_current_modulator.Value = 0;
                West.carrier = West.carrier .* 0;
                West.modulator = West.modulator .* 0;
                West.stimulus = West.stimulus .* 0;
                West.stimulus_trigger = West.stimulus_trigger .* 0;
                West.pulse_trigger = West.pulse_trigger .* 0;
            elseif app.Speaker.Value == "West"
                app.East_current_carrier.Value = 0;
                app.East_current_modulator.Value = 0;
                app.West_current_carrier.Value = carrier_frequency;
                app.West_current_modulator.Value = modulator_frequency;
                East.carrier = East.carrier .* 0;
                East.modulator = East.modulator .* 0;
                East.stimulus = East.stimulus .* 0;
                East.stimulus_trigger = East.stimulus_trigger .* 0;
                East.pulse_trigger = East.pulse_trigger .* 0;
            else
                app.East_current_carrier.Value = carrier_frequency;
                app.East_current_modulator.Value = modulator_frequency;
                app.West_current_carrier.Value = carrier_frequency;
                app.West_current_modulator.Value = modulator_frequency;
            end
            time = East.time;
            carrier = cat(1, East.carrier, West.carrier);
            modulator = cat(1, East.modulator, West.modulator);
            stimulus = cat(1, East.stimulus, West.stimulus);
            stimulus_trigger = cat(1, East.stimulus_trigger, West.stimulus_trigger);
            pulse_trigger = cat(1, East.pulse_trigger, West.pulse_trigger);
        end
        
        function e = transfer_search_parameters(app, stimulus, stimulus_trigger, pulse_trigger)
            e = [];
            e(end+1) = app.RPcontroller.WriteTagV('EastPulseTrigger', 0, pulse_trigger(1, :));
            e(end+1) = app.RPcontroller.WriteTagV('EastStimulusTrigger', 0, stimulus_trigger(1, :));
            e(end+1) = app.RPcontroller.WriteTagV('EastStimulus', 0, stimulus(1, :));
            e(end+1) = app.RPcontroller.SetTagVal('EastStimulusPoints', size(stimulus, 2));
            e(end+1) = app.RPcontroller.WriteTagV('WestPulseTrigger', 0, pulse_trigger(2, :));
            e(end+1) = app.RPcontroller.WriteTagV('WestStimulusTrigger', 0, stimulus_trigger(2, :));
            e(end+1) = app.RPcontroller.WriteTagV('WestStimulus', 0, stimulus(2, :));
            e(end+1) = app.RPcontroller.SetTagVal('WestStimulusPoints', size(stimulus, 2));
            e = prod(e, 'all');
            if e
                app.LoadParametersLamp.Color = [0, 1, 0];
            else
                app.LoadParametersLamp.Color = [1, 0, 0];
            end
        end
        
        function [carrier_freq, carrier_fft, modulator_freq, modulator_fft, stimulus_fft] = get_fft(app, time, carrier, modulator, stimulus, max_fc, max_fm)
            [~, carrier_fft] = app.compute_fft(carrier, time);
            [~, modulator_fft] = app.compute_fft(modulator, time);
            [n, stimulus_fft] = app.compute_fft(stimulus, time);
            freq = 0:(app.sampling_rate/n):(app.sampling_rate/2 - app.sampling_rate/n);
            carrier_freq = freq(freq <= (max_fc+200));
            modulator_freq = freq(freq <= (max_fm+20));
            carrier_fft = carrier_fft(:, 1:size(carrier_freq, 2));
            modulator_fft = modulator_fft(:, 1:size(modulator_freq, 2));
            stimulus_fft = stimulus_fft(:, 1:size(carrier_freq, 2));
        end
        
        function search_plot_stimuli(app, time, carrier, modulator, stimulus, max_fc, max_fm)
            plot_type = app.East_plot_type.Value;
            if plot_type == "Time"
                plot(app.East_carrier_plot, carrier(1, :), 'LineWidth', 0.5, "XData", time);
                plot(app.East_modulator_plot, modulator(1, :), 'LineWidth', 0.5, "XData", time);
                plot(app.East_stimulus_plot, stimulus(1, :), 'LineWidth', 0.5, "XData", time);
            elseif plot_type == "FFT"
                [carrier_freq, carrier_fft, modulator_freq, modulator_fft, stimulus_fft] = app.get_fft(time, carrier(1, :), modulator(1, :), stimulus(1, :), max_fc, max_fm);
                plot(app.East_carrier_plot, carrier_fft, 'LineWidth', 0.5, "XData", carrier_freq);
                plot(app.East_modulator_plot, modulator_fft, 'LineWidth', 0.5, "XData", modulator_freq);
                plot(app.East_stimulus_plot, stimulus_fft, 'LineWidth', 0.5, "XData", carrier_freq);
            end
            plot_type = app.West_plot_type.Value;
            if plot_type == "Time"
                plot(app.West_carrier_plot, carrier(2, :), 'LineWidth', 0.5, "XData", time);
                plot(app.West_modulator_plot, modulator(2, :), 'LineWidth', 0.5, "XData", time);
                plot(app.West_stimulus_plot, stimulus(2, :), 'LineWidth', 0.5, "XData", time);
            elseif plot_type == "FFT"
                [carrier_freq, carrier_fft, modulator_freq, modulator_fft, stimulus_fft] = app.get_fft(time, carrier(2, :), modulator(2, :), stimulus(2, :), max_fc, max_fm);
                plot(app.West_carrier_plot, carrier_fft, 'LineWidth', 0.5, "XData", carrier_freq);
                plot(app.West_modulator_plot, modulator_fft, 'LineWidth', 0.5, "XData", modulator_freq);
                plot(app.West_stimulus_plot, stimulus_fft, 'LineWidth', 0.5, "XData", carrier_freq);
            end
        end
        
        function freq = get_PRR_modulator(app)
            if app.PRR_mode.Value == "Select"
                f = [];
                if app.Fm_PRR_1.Enable == 1
                    f(end+1) = app.Fm_PRR_1.Value;
                end
                if app.Fm_PRR_2.Enable == 1
                    f(end+1) = app.Fm_PRR_2.Value;
                end
                if app.Fm_PRR_3.Enable == 1
                    f(end+1) = app.Fm_PRR_3.Value;
                end
                if app.Fm_PRR_4.Enable == 1
                    f(end+1) = app.Fm_PRR_4.Value;
                end
                if app.Fm_PRR_5.Enable == 1
                    f(end+1) = app.Fm_PRR_5.Value;
                end
                freq.west = f;
                freq.east = f;
            elseif app.PRR_mode.Value == "Cycle"
                f = app.Fm_PRR_start.Value: app.Fm_PRR_step.Value: app.Fm_PRR_end.Value;
                freq.west = f;
                freq.east = f;
            elseif app.PRR_mode.Value == "Independent"
                freq.west = app.West_PRR_Pulse_rate.Value;
                freq.east = app.East_PRR_Pulse_rate.Value;
            end
        end
        
        function [f, A] = get_PRR_carrier(app)
            f = [];
            A = [];
            if app.Fc_PRR_1.Enable == 1
                f(end+1) = app.Fc_PRR_1.Value;
                A(end+1) = app.Ec_PRR_1.Value;
            end
            if app.Fc_PRR_2.Enable == 1
                f(end+1) = app.Fc_PRR_2.Value;
                A(end+1) = app.Ec_PRR_2.Value;
            end
            if app.Fc_PRR_3.Enable == 1
                f(end+1) = app.Fc_PRR_3.Value;
                A(end+1) = app.Ec_PRR_3.Value;
            end
            if app.Fc_PRR_4.Enable == 1
                f(end+1) = app.Fc_PRR_4.Value;
                A(end+1) = app.Ec_PRR_4.Value;
            end
            if app.Fc_PRR_5.Enable == 1
                f(end+1) = app.Fc_PRR_5.Value;
                A(end+1) = app.Ec_PRR_5.Value;
            end
        end
        
        function carrier = build_PRR_carrier(app, time)
            [fcs, A] = app.get_PRR_carrier();
            carrier = zeros(size(time));
            for i = 1: 1: length(fcs)
                carrier = carrier + A(i)*sin(2*pi*fcs(i).*time);
            end
            carrier = normalize(carrier, 'range', [-1 1]);
            app.East_current_carrier.Value = 0;
            app.West_current_carrier.Value = 0;
        end
        
        function [rise_time, on_pulse, off_pulse] = build_triangular_modulator(app, rt, pulse_on_duration, pulse_off_duration)
            if rt.units == "%"
                rise_time = pulse_on_duration*(rt.value/100);
            elseif rt.units == "msec"
                rise_time = min(pulse_on_duration, rt.value*1e-3);
            end
            fall_time = pulse_on_duration - rise_time;
            time = 0: (1/app.sampling_rate): (rise_time - (1/app.sampling_rate));
            rise_pulse = (1/rise_time).*time;
            time = rise_time: (1/app.sampling_rate): (pulse_on_duration - (1/app.sampling_rate));
            fall_pulse = ((-1/fall_time).*(time-rise_time)) + 1;
            on_pulse = cat(2, rise_pulse, fall_pulse);
            off_pulse = zeros(1, floor(pulse_off_duration*app.sampling_rate));
        end
        
        function  [on_pulse, off_pulse] = build_exponential_modulator(app, pulse_on_duration, pulse_off_duration)
            time = 0: (1/app.sampling_rate): (pulse_on_duration - (1/app.sampling_rate));
            tau = pulse_on_duration/10;
            exponent = time./tau;
            on_pulse = exponent.*exp(-1.*exponent + 1);
            off_pulse = zeros(1, floor(pulse_off_duration*app.sampling_rate));
        end
        
        function [on_pulse, off_pulse] = build_rectangular_modulator(app, pulse_on_duration, pulse_off_duration)
            time = 0: (1/app.sampling_rate): pulse_on_duration-(1/app.sampling_rate);
            on_pulse = ones(size(time));
            time = 0: (1/app.sampling_rate): pulse_off_duration-(1/app.sampling_rate);
            off_pulse = zeros(size(time));
        end
        
        function [pulse_on_duration, rise_time, carrier, pulses, triggers] = build_PRR_pulse_train(app, fm, npulses, pd, rt, gap)
            total_pulse_duration = floor(1000.0/fm)*1e-3;
            if pd.units == "%"
                pulse_on_duration = total_pulse_duration*(pd.value/100);
            elseif pd.units == "msec"
                pulse_on_duration = min(total_pulse_duration, pd.value*1e-3);
            end
            pulse_off_duration = total_pulse_duration - pulse_on_duration;
            envelope = app.PRREnvelope.Value;
            if envelope == "Triangular"
                [rise_time, on_pulse, off_pulse] = app.build_triangular_modulator(rt, pulse_on_duration, pulse_off_duration);
            elseif envelope == "Exponential"
                [on_pulse, off_pulse] = app.build_exponential_modulator(pulse_on_duration, pulse_off_duration);
                rise_time = 0;
            elseif envelope == "Rectangular"
                [on_pulse, off_pulse] = app.build_rectangular_modulator(pulse_on_duration, pulse_off_duration);
                rise_time = 0;
            end
            pulse = cat(2, on_pulse, off_pulse);
            carrier_pulse = cat(2, app.build_PRR_carrier(0:(1/app.sampling_rate):((size(on_pulse, 2)-1)/app.sampling_rate)), off_pulse);
            trigger = zeros(1, size(pulse, 2));
            trigger(1, 1) = 1;
            pulses = [];
            triggers = [];
            carrier = [];
            for i = 1: 1: npulses
                if i == gap.after_pulse
%                     pulses = cat(2, pulses, pulse(1, 1:size(on_pulse, 2)), zeros(1, floor(gap.time*app.sampling_rate)));
%                     triggers = cat(2, triggers, trigger(1, 1:size(on_pulse, 2)), zeros(1, floor(gap.time*app.sampling_rate)));
%                     carrier = cat(2, carrier, carrier_pulse(1, 1:size(on_pulse, 2)), zeros(1, floor(gap.time*app.sampling_rate)));
                    pulses = cat(2, pulses, pulse, zeros(1, floor(gap.time*app.sampling_rate)));
                    triggers = cat(2, triggers, trigger, zeros(1, floor(gap.time*app.sampling_rate)));
                    carrier = cat(2, carrier, carrier_pulse, zeros(1, floor(gap.time*app.sampling_rate)));
                else
                    pulses = cat(2, pulses, pulse);
                    triggers = cat(2, triggers, trigger);
                    carrier = cat(2, carrier, carrier_pulse);
                end
            end
        end
        
        function [times, carriers, modulators, stimuli, pulse_triggers, stimulus_triggers] = build_PRR_stimulus(app, fm_east, fm_west)
            pd.units = app.West_PRR_PD_units.Value;
            pd.value = app.West_PRR_PD.Value;
            rt.units = app.West_PRR_RT_units.Value;
            rt.value = app.West_PRR_RT.Value;
            if app.PRR_mode.Value == "Independent"
                gap.after_pulse = app.West_PRR_Gap_after_pulse.Value;
                gap.time = app.West_PRR_Gap_time.Value*1e-3;
            else
                gap.after_pulse = 0;
                gap.time = 0;
            end
            if app.West_PRR_nPulses.Enable == 1
                npulses = app.West_PRR_nPulses.Value;
            else
               npulses = floor((fm_west/1000)*400); 
            end
            [pulse_on_duration, rise_time, carriers.west, modulators.west, pulse_triggers.west] = app.build_PRR_pulse_train(fm_west, npulses, pd, rt, gap);
            if app.West_PRR_PD_units.Value == "msec"
                app.West_PRR_PD.Value = pulse_on_duration*1e3;
            end
            if app.West_PRR_RT_units.Value == "msec"
                app.West_PRR_RT.Value = rise_time*1e3;
            end
            pd.units = app.East_PRR_PD_units.Value;
            pd.value = app.East_PRR_PD.Value;
            rt.units = app.East_PRR_RT_units.Value;
            rt.value = app.East_PRR_RT.Value;
            if app.East_PRR_nPulses.Enable == 1
                npulses = app.East_PRR_nPulses.Value;
            else
               npulses = floor((fm_east/1000)*400); 
            end
            if app.PRR_mode.Value == "Independent"
                gap.after_pulse = app.East_PRR_Gap_after_pulse.Value;
                gap.time = app.East_PRR_Gap_time.Value*1e-3;
            else
                gap.after_pulse = 0;
                gap.time = 0;
            end
            [pulse_on_duration, rise_time, carriers.east, modulators.east, pulse_triggers.east] = app.build_PRR_pulse_train(fm_east, npulses, pd, rt, gap);
            if app.East_PRR_PD_units.Value == "msec"
                app.East_PRR_PD.Value = pulse_on_duration*1e3;
            end
            if app.East_PRR_RT_units.Value == "msec"
                app.East_PRR_RT.Value = rise_time*1e3;
            end
            stimuli.west = 10*modulators.west.*carriers.west;
            stimulus_triggers.west = zeros(size(stimuli.west));
            stimulus_triggers.west(1,1) = 1;
            stimuli.east = 10*modulators.east.*carriers.east;
            stimulus_triggers.east = zeros(size(stimuli.east));
            stimulus_triggers.east(1,1) = 1;
            if app.Speaker.Value == "Biaural"
                speaker_interval = app.Inter_speaker_interval.Value * 1e-3;
                speaker_interval_samples = zeros(1, floor(abs(speaker_interval)*app.sampling_rate));
                if speaker_interval >= 0
                   modulators.east = cat(2, speaker_interval_samples, modulators.east);
                   carriers.east = cat(2, speaker_interval_samples, carriers.east);
                   pulse_triggers.east = cat(2, speaker_interval_samples, pulse_triggers.east);
                   stimuli.east = cat(2, speaker_interval_samples, stimuli.east);
                   stimulus_triggers.east = cat(2, speaker_interval_samples, stimulus_triggers.east);
                   modulators.west = cat(2, modulators.west, speaker_interval_samples);
                   carriers.west = cat(2, carriers.west, speaker_interval_samples);
                   pulse_triggers.west = cat(2, pulse_triggers.west, speaker_interval_samples);
                   stimuli.west = cat(2, stimuli.west, speaker_interval_samples);
                   stimulus_triggers.west = cat(2, stimulus_triggers.west, speaker_interval_samples);
                else
                   modulators.west = cat(2, speaker_interval_samples, modulators.west);
                   carriers.west = cat(2, speaker_interval_samples, carriers.west);
                   pulse_triggers.west = cat(2, speaker_interval_samples, pulse_triggers.west);
                   stimuli.west = cat(2, speaker_interval_samples, stimuli.west);
                   stimulus_triggers.west = cat(2, speaker_interval_samples, stimulus_triggers.west);
                   modulators.east = cat(2, modulators.east, speaker_interval_samples);
                   carriers.east = cat(2, carriers.east, speaker_interval_samples);
                   pulse_triggers.east = cat(2, pulse_triggers.east, speaker_interval_samples);
                   stimuli.east = cat(2, stimuli.east, speaker_interval_samples);
                   stimulus_triggers.east = cat(2, stimulus_triggers.east, speaker_interval_samples);
                end
                app.East_current_modulator.Value = fm_east;
                app.West_current_modulator.Value = fm_west;
            end
            if app.Speaker.Value == "West"
                carriers.east = carriers.east * 0;
                modulators.east = modulators.east * 0;
                pulse_triggers.east = pulse_triggers.east * 0;
                stimuli.east = stimuli.east * 0;
                stimulus_triggers.east = stimulus_triggers.east * 0;
                app.East_current_modulator.Value = 0;
                app.West_current_modulator.Value = fm_west;
            elseif app.Speaker.Value == "East"
                carriers.west = carriers.west * 0;
                modulators.west = modulators.west * 0;
                pulse_triggers.west = pulse_triggers.west * 0;
                stimuli.west = stimuli.west * 0;
                stimulus_triggers.west = stimulus_triggers.west * 0;
                app.East_current_modulator.Value = fm_east;
                app.West_current_modulator.Value = 0;
            end
            times.west = 0: (1/app.sampling_rate): ((size(modulators.west, 2)-1)/app.sampling_rate);
            times.east = 0: (1/app.sampling_rate): ((size(modulators.east, 2)-1)/app.sampling_rate);
        end
        
        function e = transfer_PRR_parameters(app, stimulus, stimulus_trigger, pulse_trigger)
            e = [];
            e(end+1) = app.RPcontroller.WriteTagV('EastPulseTrigger', 0, pulse_trigger.east);
            e(end+1) = app.RPcontroller.WriteTagV('EastStimulusTrigger', 0, stimulus_trigger.east);
            e(end+1) = app.RPcontroller.WriteTagV('EastStimulus', 0, stimulus.east);
            e(end+1) = app.RPcontroller.SetTagVal('EastStimulusPoints', size(stimulus.east, 2));
            e(end+1) = app.RPcontroller.WriteTagV('WestPulseTrigger', 0, pulse_trigger.west);
            e(end+1) = app.RPcontroller.WriteTagV('WestStimulusTrigger', 0, stimulus_trigger.west);
            e(end+1) = app.RPcontroller.WriteTagV('WestStimulus', 0, stimulus.west);
            e(end+1) = app.RPcontroller.SetTagVal('WestStimulusPoints', size(stimulus.west, 2));
            e = prod(e, 'all');
            if e
                app.LoadParametersLamp.Color = [0, 1, 0];
            else
                app.LoadParametersLamp.Color = [1, 0, 0];
            end
        end
        
        function plot_PRR(app, times, carriers, modulators, stimuli, max_fc, max_fm)
            plot_type = app.East_plot_type.Value;
            if plot_type == "Time"
                plot(app.East_carrier_plot, carriers.east, 'LineWidth', 0.5, "XData", times.east);
                plot(app.East_modulator_plot, modulators.east, 'LineWidth', 0.5, "XData", times.east);
                plot(app.East_stimulus_plot, stimuli.east, 'LineWidth', 0.5, "XData", times.east);
            elseif plot_type == "FFT"
                [carrier_freq, carrier_fft, modulator_freq, modulator_fft, stimulus_fft] = app.get_fft(times.east, carriers.east, modulators.east, stimuli.east, max_fc, max_fm.east);
                plot(app.East_carrier_plot, carrier_fft, 'LineWidth', 0.5, "XData", carrier_freq);
                plot(app.East_modulator_plot, modulator_fft, 'LineWidth', 0.5, "XData", modulator_freq);
                plot(app.East_stimulus_plot, stimulus_fft, 'LineWidth', 0.5, "XData", carrier_freq);
            end
            plot_type = app.West_plot_type.Value;
            if plot_type == "Time"
                plot(app.West_carrier_plot, carriers.west, 'LineWidth', 0.5, "XData", times.west);
                plot(app.West_modulator_plot, modulators.west, 'LineWidth', 0.5, "XData", times.west);
                plot(app.West_stimulus_plot, stimuli.west, 'LineWidth', 0.5, "XData", times.west);
            elseif plot_type == "FFT"
                [carrier_freq, carrier_fft, modulator_freq, modulator_fft, stimulus_fft] = app.get_fft(times.west, carriers.west, modulators.west, stimuli.west, max_fc, max_fm.west);
                plot(app.West_carrier_plot, carrier_fft, 'LineWidth', 0.5, "XData", carrier_freq);
                plot(app.West_modulator_plot, modulator_fft, 'LineWidth', 0.5, "XData", modulator_freq);
                plot(app.West_stimulus_plot, stimulus_fft, 'LineWidth', 0.5, "XData", carrier_freq);
            end
        end
        
        function natural_calls_initialization(app)
            % Rana pipiens
            listing = dir("./calls/Rpipiens/");
            listing = listing(3:end);
            app.calls_list.Rpipiens = strings(length(listing), 1);
            app.calls.Rpipiens = cell(length(listing), 1);
            for i = 1: 1: length(listing)
                app.calls_list.Rpipiens{i, 1} = listing(i).name;
                [call, ~] = audioread(strcat("./calls/Rpipiens/", listing(i).name));
                app.calls.Rpipiens{i, 1} = 10.*normalize(call', 'range', [-1 1]);
                app.calls.Rpipiens{i, 1} = app.calls.Rpipiens{i, 1}(1, :) - app.calls.Rpipiens{i, 1}(1, 1);
            end
            
            % Hyla versicolor
            listing = dir("./calls/Hversicolor/");
            listing = listing(3:end);
            app.calls_list.Hversicolor = strings(length(listing), 1);
            app.calls.Hversicolor = cell(length(listing), 1);
            for i = 1: 1: length(listing)
                app.calls_list.Hversicolor{i, 1} = listing(i).name;
                [call, ~] = audioread(strcat("./calls/Hversicolor/", listing(i).name));
                app.calls.Hversicolor{i, 1} = 10.*normalize(call', 'range', [-1 1]);
                app.calls.Hversicolor{i, 1} = app.calls.Hversicolor{i, 1}(1, :) - app.calls.Hversicolor{i, 1}(1, 1);
            end
            
            % Hyla chrysocelis
            listing = dir("./calls/Hchrysocelis/");
            listing = listing(3:end);
            app.calls_list.Hchrysocelis = strings(length(listing), 1);
            app.calls.Hchrysocelis = cell(length(listing), 1);
            for i = 1: 1: length(listing)
                app.calls_list.Hchrysocelis{i, 1} = listing(i).name;
                [call, ~] = audioread(strcat("./calls/Hchrysocelis/", listing(i).name));
                app.calls.Hchrysocelis{i, 1} = 10.*normalize(call', 'range', [-1 1]);
                app.calls.Hchrysocelis{i, 1} = app.calls.Hchrysocelis{i, 1}(1, :) - app.calls.Hchrysocelis{i, 1}(1, 1);
            end
            
            % Pseudacris feriarum
            listing = dir("./calls/Pferiarum/");
            listing = listing(3:end);
            app.calls_list.Pferiarum = strings(length(listing), 1);
            app.calls.Pferiarum = cell(length(listing), 1);
            for i = 1: 1: length(listing)
                app.calls_list.Pferiarum{i, 1} = listing(i).name;
                [call, ~] = audioread(strcat("./calls/Pferiarum/", listing(i).name));
                app.calls.Pferiarum{i, 1} = 10.*normalize(call', 'range', [-1 1]);
                app.calls.Pferiarum{i, 1} = app.calls.Pferiarum{i, 1}(1, :) - app.calls.Pferiarum{i, 1}(1, 1);
            end
        end
        
        function [stimulus, stimulus_triggers, pulse_triggers, stimulus_time] = build_natural_call(app)
            species = app.SpeciesDropDown.Value;
            if species == "Rana pipiens"
                call_index = find(strcmp(app.calls_list.Rpipiens, app.NaturalCall.Value));
                call = app.calls.Rpipiens(call_index, 1);
            elseif species == "Hyla versicolor"
                call_index = find(strcmp(app.calls_list.Hversicolor, app.NaturalCall.Value));
                call = app.calls.Hversicolor(call_index, 1);
            elseif species == "Hyla chrysocelis"
                call_index = find(strcmp(app.calls_list.Hchrysocelis, app.NaturalCall.Value));
                call = app.calls.Hchrysocelis(call_index, 1);
            elseif species == "Pseudacris feriarum"
                call_index = find(strcmp(app.calls_list.Pferiarum, app.NaturalCall.Value));
                call = app.calls.Pferiarum(call_index, 1);
            end
            call = cell2mat(call);
            if app.NaturalPlayMode.Value == "Reversed"
                call = fliplr(call);
            end
            stimulus.east = call;
            stimulus_triggers.east = zeros(size(stimulus.east));
            pulse_triggers.east = zeros(size(stimulus.east));
            stimulus.west = call;
            stimulus_triggers.west = zeros(size(stimulus.west));
            pulse_triggers.west = zeros(size(stimulus.west));
            speaker_interval = app.Inter_speaker_interval.Value * 1e-3;
            speaker_interval_samples = zeros(1, floor(abs(speaker_interval)*app.sampling_rate));
            if speaker_interval >= 0
                stimulus.east = cat(2, speaker_interval_samples, stimulus.east);
                stimulus_triggers.east(1, 1) = 1;
                stimulus_triggers.east = cat(2, speaker_interval_samples, stimulus_triggers.east);
                pulse_triggers.east = cat(2, speaker_interval_samples, pulse_triggers.east);
                stimulus.west = cat(2, stimulus.west, speaker_interval_samples);
                stimulus_triggers.west(1, 1) = 1;
                stimulus_triggers.west = cat(2, stimulus_triggers.west, speaker_interval_samples);
                pulse_triggers.west = cat(2, speaker_interval_samples, pulse_triggers.west);
            elseif speaker_interval < 0
                stimulus.west = cat(2, speaker_interval_samples, stimulus.west);
                stimulus_triggers.west(1, 1) = 1;
                stimulus_triggers.west = cat(2, speaker_interval_samples, stimulus_triggers.west);
                pulse_triggers.west = cat(2, speaker_interval_samples, pulse_triggers.west);
                stimulus.east = cat(2, stimulus.east, speaker_interval_samples);
                stimulus_triggers.east(1, 1) = 1;
                stimulus_triggers.east = cat(2, stimulus_triggers.east, speaker_interval_samples);
                pulse_triggers.east = cat(2, speaker_interval_samples, pulse_triggers.east);
            end
            if app.Speaker.Value == "East"
                stimulus.west = stimulus.west .* 0;
                stimulus_triggers.west = stimulus_triggers.west .* 0;
                pulse_triggers.west = pulse_triggers.west .* 0;
            elseif app.Speaker.Value == "West"
                stimulus.east = stimulus.east .* 0;
                stimulus_triggers.east = stimulus_triggers.east .* 0;
                pulse_triggers.east = pulse_triggers.east .* 0;
            end
            stimulus_time.east = (0: 1: size(stimulus.east, 2)-1)/app.sampling_rate;
            stimulus_time.west = (0: 1: size(stimulus.west, 2)-1)/app.sampling_rate;
        end
        
        function [freq, stimulus_fft] = call_fft(app, stimulus, time)
            [n, stimulus_fft] = app.compute_fft(stimulus, time);
            freq = 0:(app.sampling_rate/n):(app.sampling_rate/2 - app.sampling_rate/n);
            if app.SpeciesDropDown.Value == "Rana pipiens"
                freq = freq(freq <= (1600+200));
            elseif app.SpeciesDropDown.Value == "Hyla versicolor"
                freq = freq(freq <= (2200+200));
            elseif app.SpeciesDropDown.Value == "Hyla chrysocelis"
                freq = freq(freq <= (2200+200));
            elseif app.SpeciesDropDown.Value == "Pseudacris feriarum"
                freq = freq(freq <= (3100+500));
            end
            stimulus_fft = stimulus_fft(:, 1:size(freq, 2));
        end
        
        function plot_calls(app, stimulus, stimulus_time)
            plot_type = app.East_plot_type.Value;
            if plot_type == "Time"
                plot(app.East_carrier_plot, stimulus.east.*0, 'LineWidth', 0.5, "XData", stimulus_time.east);
                plot(app.East_modulator_plot, stimulus.east.*0, 'LineWidth', 0.5, "XData", stimulus_time.east);
                plot(app.East_stimulus_plot, stimulus.east, 'LineWidth', 0.5, "XData", stimulus_time.east);
            elseif plot_type == "FFT"
                [freq, stimulus_fft] = app.call_fft(stimulus.east, stimulus_time.east);
                plot(app.East_carrier_plot, stimulus_fft.*0, 'LineWidth', 0.5, "XData", freq);
                plot(app.East_modulator_plot, stimulus_fft.*0, 'LineWidth', 0.5, "XData", freq);
                plot(app.East_stimulus_plot, stimulus_fft, 'LineWidth', 0.5, "XData", freq);
            end
            plot_type = app.West_plot_type.Value;
            if plot_type == "Time"
                plot(app.West_carrier_plot, stimulus.west.*0, 'LineWidth', 0.5, "XData", stimulus_time.west);
                plot(app.West_modulator_plot, stimulus.west.*0, 'LineWidth', 0.5, "XData", stimulus_time.west);
                plot(app.West_stimulus_plot, stimulus.west, 'LineWidth', 0.5, "XData", stimulus_time.west);
            elseif plot_type == "FFT"
                [freq, stimulus_fft] = app.call_fft(stimulus.west, stimulus_time.west);
                plot(app.West_carrier_plot, stimulus_fft.*0, 'LineWidth', 0.5, "XData", freq);
                plot(app.West_modulator_plot, stimulus_fft.*0, 'LineWidth', 0.5, "XData", freq);
                plot(app.West_stimulus_plot, stimulus_fft, 'LineWidth', 0.5, "XData", freq);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, debug)
            arguments
                app;
                debug(1, 1) logical {mustBeNumericOrLogical} = false;
            end
            app.debug = debug;
            if app.debug == false
                app.hardware_initialization();
            end
            app.natural_calls_initialization();
            app.SpeciesDropDownValueChanged();
        end

        % Value changed function: SpeciesDropDown
        function SpeciesDropDownValueChanged(app, event)
            value = app.SpeciesDropDown.Value;
            if value == "Rana pipiens"
                app.Fc_5.Value = 1600;
                app.Fc_PRR_5.Value = 1600;
                app.NaturalCall.Items = app.calls_list.Rpipiens;
            elseif value == "Hyla versicolor"
                app.Fc_5.Value = 2200;
                app.Fc_PRR_5.Value = 2200;
                app.NaturalCall.Items = app.calls_list.Hversicolor;
            elseif value == "Hyla chrysocelis"
                app.Fc_5.Value = 2200;
                app.Fc_PRR_5.Value = 2200;
                app.NaturalCall.Items = app.calls_list.Hchrysocelis;
            elseif value == "Pseudacris feriarum"
                app.Fc_1.Value = 200;
                app.Fc_PRR_1.Value = 200;
                app.Fc_2.Value = 400;
                app.Fc_PRR_2.Value = 400;
                app.Fc_3.Value = 700;
                app.Fc_PRR_3.Value = 700;
                app.Fc_4.Value = 1400; 
                app.Fc_PRR_4.Value = 1400;
                app.Fc_5.Value = 3100;
                app.Fc_PRR_5.Value = 3100;
                app.NaturalCall.Items = app.calls_list.Pferiarum;
            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            if value == "Normal"
                app.search_mode_normal();
            elseif value == "Fixed Carrier"
                app.search_mode_fixed_carrier();
            elseif value == "Fixed Modulation"
                app.search_mode_fixed_modulation();
            end
        end

        % Value changed function: Fc_check_1
        function Fc_check_1ValueChanged(app, event)
            value = app.Fc_check_1.Value;
            if value == 0
                app.Fc_1.Enable = 0;
            else
                app.Fc_1.Enable = 1;
            end
        end

        % Value changed function: Fc_check_2
        function Fc_check_2ValueChanged(app, event)
            value = app.Fc_check_2.Value;
            if value == 0
                app.Fc_2.Enable = 0;
            else
                app.Fc_2.Enable = 1;
            end
        end

        % Value changed function: Fc_check_3
        function Fc_check_3ValueChanged(app, event)
            value = app.Fc_check_3.Value;
            if value == 0
                app.Fc_3.Enable = 0;
            else
                app.Fc_3.Enable = 1;
            end
        end

        % Value changed function: Fc_check_4
        function Fc_check_4ValueChanged(app, event)
            value = app.Fc_check_4.Value;
            if value == 0
                app.Fc_4.Enable = 0;
            else
                app.Fc_4.Enable = 1;
            end
        end

        % Value changed function: Fc_check_5
        function Fc_check_5ValueChanged(app, event)
            value = app.Fc_check_5.Value;
            if value == 0
                app.Fc_5.Enable = 0;
            else
                app.Fc_5.Enable = 1;
            end
        end

        % Value changed function: Fm_check_1
        function Fm_check_1ValueChanged(app, event)
            value = app.Fm_check_1.Value;
            if value == 0
                app.Fm_1.Enable = 0;
            else
                app.Fm_1.Enable = 1;
            end
        end

        % Value changed function: Fm_check_2
        function Fm_check_2ValueChanged(app, event)
            value = app.Fm_check_2.Value;
            if value == 0
                app.Fm_2.Enable = 0;
            else
                app.Fm_2.Enable = 1;
            end
        end

        % Value changed function: Fm_check_3
        function Fm_check_3ValueChanged(app, event)
            value = app.Fm_check_3.Value;
            if value == 0
                app.Fm_3.Enable = 0;
            else
                app.Fm_3.Enable = 1;
            end
        end

        % Value changed function: Fm_check_4
        function Fm_check_4ValueChanged(app, event)
            value = app.Fm_check_4.Value;
            if value == 0
                app.Fm_4.Enable = 0;
            else
                app.Fm_4.Enable = 1;
            end
        end

        % Value changed function: Fm_check_5
        function Fm_check_5ValueChanged(app, event)
            value = app.Fm_check_5.Value;
            if value == 0
                app.Fm_5.Enable = 0;
            else
                app.Fm_5.Enable = 1;
            end
        end

        % Value changed function: ControlSwitch
        function ControlSwitchValueChanged(app, event)
            while app.ControlSwitch.Value == "On"
                tab = app.TabGroup.SelectedTab.Title;
                if tab == "Search"
                    fcs = app.search_get_carriers();
                    fms = app.search_get_modulators();
                    for i = 1: 1: size(fcs, 2)
                        for j = 1: 1: size(fms, 2)
                            if app.ControlSwitch.Value == "Off"
                                return;
                            end
                            [time, carrier, modulator, stimulus, stimulus_trigger, pulse_trigger] = app.build_biaural_search_stimulus(fcs(1, i), fms(1, j));
                            if app.debug == false
                                app.RPcontroller.ClearCOF();
                                if app.load_circuit("biaural_circuit.rcx")
                                    if app.transfer_search_parameters(stimulus, stimulus_trigger, pulse_trigger)
                                        app.RPcontroller.Run();
                                        app.RPcontroller.SoftTrg(1);
                                        app.RPcontroller.SoftTrg(2);
                                        app.search_plot_stimuli(time, carrier, modulator, stimulus, max(fcs), max(fms));
                                        pause(app.ISI.Value);
                                    else
                                        return;
                                    end
                                else
                                    return;
                                end
                            else
                                app.search_plot_stimuli(time, carrier, modulator, stimulus, max(fcs), max(fms));
                                pause(app.ISI.Value);
                            end
                        end
                    end
                elseif tab == "PRR"
                    fms = app.get_PRR_modulator();
                    max_fms.east = max(fms.east);
                    max_fms.west = max(fms.west);
                    [fcs, ~] = app.get_PRR_carrier();
                    max_fcs = max(fcs);
                    for j = 1: 1: size(fms.east, 2)
                        if app.ControlSwitch.Value == "Off"
                            return;
                        end
                        [times, carriers, modulators, stimuli, pulse_triggers, stimulus_triggers] = app.build_PRR_stimulus(fms.east(1, j), fms.west(1, j));
                        if app.debug == false
                            if app.RPcontroller.ClearCOF()
                                if app.load_circuit("biaural_circuit.rcx")
                                    if app.transfer_PRR_parameters(stimuli, stimulus_triggers, pulse_triggers)
                                        app.RPcontroller.Run();
                                        app.RPcontroller.SoftTrg(1);
                                        app.RPcontroller.SoftTrg(2);
                                        app.plot_PRR(times, carriers, modulators, stimuli, max_fcs, max_fms);
                                        pause(app.ISI.Value);
                                    else
                                        return;
                                    end
                                else
                                    return;
                                end
                            else
                                return;
                            end
                        else
                            app.plot_PRR(times, carriers, modulators, stimuli, max_fcs, max_fms);
                            pause(app.ISI.Value);
                        end
                    end
                elseif tab == "Natural"
                    if app.ControlSwitch.Value == "Off"
                        return;
                    end
                    [stimulus, stimulus_triggers, pulse_triggers, stimulus_time] = app.build_natural_call();
                    if app.debug == false
                        if app.RPcontroller.ClearCOF()
                            if app.load_circuit("biaural_circuit.rcx")
                                if app.transfer_PRR_parameters(stimulus, stimulus_triggers, pulse_triggers)
                                    app.RPcontroller.Run();
                                    app.RPcontroller.SoftTrg(1);
                                    app.RPcontroller.SoftTrg(2);
                                    app.plot_calls(stimulus, stimulus_time);
                                    if stimulus_time.west(1, end) > app.ISI.Value
                                        app.ISI.Value = stimulus_time.west(1, end)+1;
                                    end
                                    pause(app.ISI.Value);
                                else
                                    return;
                                end
                            else
                                return;
                            end
                        else
                            return;
                        end
                    else
                        app.plot_calls(stimulus, stimulus_time);
                        if stimulus_time.west(1, end) > app.ISI.Value
                            app.ISI.Value = stimulus_time.west(1, end)+1;
                        end
                        pause(app.ISI.Value);
                    end
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.ControlSwitch.Value = "Off";
            delete(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
        end

        % Value changed function: Speaker
        function SpeakerValueChanged(app, event)


        end

        % Value changed function: PRR_mode
        function PRR_modeValueChanged(app, event)
            value = app.PRR_mode.Value;
            if value == "Select"
                app.Fm_PRR_check_1.Value = 1;
                app.Fm_PRR_1.Enable = 1;
                app.Fm_PRR_check_2.Value = 1;
                app.Fm_PRR_2.Enable = 1;
                app.Fm_PRR_check_2.Value = 1;
                app.Fm_PRR_2.Enable = 1;
                app.Fm_PRR_check_3.Value = 1;
                app.Fm_PRR_3.Enable = 1;
                app.Fm_PRR_check_4.Value = 1;
                app.Fm_PRR_4.Enable = 1;
                app.Fm_PRR_check_5.Value = 1;
                app.Fm_PRR_5.Enable = 1;
                app.Fm_PRR_start.Enable = 0;
                app.Fm_PRR_step.Enable = 0;
                app.Fm_PRR_end.Enable = 0;
                app.East_PRR_Pulse_rate.Enable = 0;
                app.West_PRR_Pulse_rate.Enable = 0;
                app.East_PRR_Gap_after_pulse.Enable = 0;
                app.East_PRR_Gap_time.Enable = 0;
                app.West_PRR_Gap_after_pulse.Enable = 0;
                app.West_PRR_Gap_time.Enable = 0;
            elseif value == "Cycle"
                app.Fm_PRR_check_1.Value = 0;
                app.Fm_PRR_1.Enable = 0;
                app.Fm_PRR_check_2.Value = 0;
                app.Fm_PRR_2.Enable = 0;
                app.Fm_PRR_check_2.Value = 0;
                app.Fm_PRR_2.Enable = 0;
                app.Fm_PRR_check_3.Value = 0;
                app.Fm_PRR_3.Enable = 0;
                app.Fm_PRR_check_4.Value = 0;
                app.Fm_PRR_4.Enable = 0;
                app.Fm_PRR_check_5.Value = 0;
                app.Fm_PRR_5.Enable = 0;
                app.Fm_PRR_start.Enable = 1;
                app.Fm_PRR_step.Enable = 1;
                app.Fm_PRR_end.Enable = 1;
                app.East_PRR_Pulse_rate.Enable = 0;
                app.West_PRR_Pulse_rate.Enable = 0;
                app.East_PRR_Gap_after_pulse.Enable = 0;
                app.East_PRR_Gap_time.Enable = 0;
                app.West_PRR_Gap_after_pulse.Enable = 0;
                app.West_PRR_Gap_time.Enable = 0;
            elseif value == "Independent"
                app.Fm_PRR_check_1.Value = 0;
                app.Fm_PRR_1.Enable = 0;
                app.Fm_PRR_check_2.Value = 0;
                app.Fm_PRR_2.Enable = 0;
                app.Fm_PRR_check_2.Value = 0;
                app.Fm_PRR_2.Enable = 0;
                app.Fm_PRR_check_3.Value = 0;
                app.Fm_PRR_3.Enable = 0;
                app.Fm_PRR_check_4.Value = 0;
                app.Fm_PRR_4.Enable = 0;
                app.Fm_PRR_check_5.Value = 0;
                app.Fm_PRR_5.Enable = 0;
                app.Fm_PRR_start.Enable = 0;
                app.Fm_PRR_step.Enable = 0;
                app.Fm_PRR_end.Enable = 0;
                app.East_PRR_Pulse_rate.Enable = 1;
                app.West_PRR_Pulse_rate.Enable = 1;
                app.East_PRR_Gap_after_pulse.Enable = 1;
                app.East_PRR_Gap_time.Enable = 1;
                app.West_PRR_Gap_after_pulse.Enable = 1;
                app.West_PRR_Gap_time.Enable = 1;
            end
        end

        % Value changed function: East_PRR_nPulses_check
        function East_PRR_nPulses_checkValueChanged(app, event)
            value = app.East_PRR_nPulses_check.Value;
            if value == 1
                app.East_PRR_nPulses.Enable = 1;
            else
                app.East_PRR_nPulses.Enable = 0;
            end
        end

        % Value changed function: West_PRR_nPulses_check
        function West_PRR_nPulses_checkValueChanged(app, event)
            value = app.West_PRR_nPulses_check.Value;
            if value == 1
                app.West_PRR_nPulses.Enable = 1;
            else
                app.West_PRR_nPulses.Enable = 0;
            end
        end

        % Value changed function: Fc_PRR_check_4
        function Fc_PRR_check_4ValueChanged(app, event)
            value = app.Fc_PRR_check_4.Value;
            app.Fc_PRR_4.Enable = value;
            app.Ec_PRR_4.Enable = value;
        end

        % Value changed function: Fc_PRR_check_3
        function Fc_PRR_check_3ValueChanged(app, event)
            value = app.Fc_PRR_check_3.Value;
            app.Fc_PRR_3.Enable = value;
            app.Ec_PRR_3.Enable = value;
        end

        % Value changed function: Fc_PRR_check_2
        function Fc_PRR_check_2ValueChanged(app, event)
            value = app.Fc_PRR_check_2.Value;
            app.Fc_PRR_2.Enable = value;
            app.Ec_PRR_2.Enable = value;
        end

        % Value changed function: Fc_PRR_check_1
        function Fc_PRR_check_1ValueChanged(app, event)
            value = app.Fc_PRR_check_1.Value;
            app.Fc_PRR_1.Enable = value;
            app.Ec_PRR_1.Enable = value;
        end

        % Value changed function: Fc_PRR_check_5
        function Fc_PRR_check_5ValueChanged(app, event)
            value = app.Fc_PRR_check_5.Value;
            app.Fc_PRR_5.Enable = value;
            app.Ec_PRR_5.Enable = value;
        end

        % Value changed function: Fm_PRR_check_1
        function Fm_PRR_check_1ValueChanged(app, event)
            app.Fm_PRR_1.Enable = app.Fm_PRR_check_1.Value;
        end

        % Value changed function: Fm_PRR_check_2
        function Fm_PRR_check_2ValueChanged(app, event)
            app.Fm_PRR_2.Enable = app.Fm_PRR_check_2.Value;
        end

        % Value changed function: Fm_PRR_check_3
        function Fm_PRR_check_3ValueChanged(app, event)
            app.Fm_PRR_3.Enable = app.Fm_PRR_check_3.Value; 
        end

        % Value changed function: Fm_PRR_check_4
        function Fm_PRR_check_4ValueChanged(app, event)
            app.Fm_PRR_4.Enable = app.Fm_PRR_check_4.Value;
        end

        % Value changed function: Fm_PRR_check_5
        function Fm_PRR_check_5ValueChanged(app, event)
            app.Fm_PRR_5.Enable = app.Fm_PRR_check_5.Value;
        end

        % Value changed function: PRREnvelope
        function PRREnvelopeValueChanged(app, event)
            value = app.PRREnvelope.Value;
            if value == "Rectangular"
                app.West_PRR_nPulses.Value = 1;
                app.East_PRR_nPulses.Value = 1;
                app.West_PRR_PD_units.Value = "msec";
                app.West_PRR_PD.Value = 20;
                app.East_PRR_PD_units.Value = "msec";
                app.East_PRR_PD.Value = 20;
                app.West_PRR_RT_units.Enable = 1;
                app.West_PRR_RT.Enable = 1;
                app.East_PRR_RT_units.Enable = 1;
                app.East_PRR_RT.Enable=1;
                app.West_PRR_RT_units.Value = "msec";
                app.West_PRR_RT.Value = 0;
                app.East_PRR_RT_units.Value = "msec";
                app.East_PRR_RT.Value = 0;
                app.PRR_mode.Value = "Independent";
                app.PRR_modeValueChanged();
                app.PRR_mode.Enable = 0;
                app.West_PRR_Pulse_rate.Value = 1;
                app.East_PRR_Pulse_rate.Value = 1;
                app.West_PRR_Gap_after_pulse.Enable = 0;
                app.East_PRR_Gap_after_pulse.Enable = 0;
                app.West_PRR_Gap_time.Enable = 0;
                app.East_PRR_Gap_time.Enable = 0;
            elseif value == "Triangular"
                app.West_PRR_nPulses.Value = 5;
                app.East_PRR_nPulses.Value = 5;
                app.West_PRR_PD_units.Value = "msec";
                app.West_PRR_PD.Value = 15;
                app.East_PRR_PD_units.Value = "msec";
                app.East_PRR_PD.Value = 15;
                app.West_PRR_RT_units.Enable = 1;
                app.West_PRR_RT.Enable = 1;
                app.East_PRR_RT_units.Enable = 1;
                app.East_PRR_RT.Enable=1;
                app.West_PRR_RT_units.Value = "%";
                app.West_PRR_RT.Value = 80;
                app.East_PRR_RT_units.Value = "%";
                app.East_PRR_RT.Value = 20;
                app.PRR_mode.Value = "Select";
                app.PRR_modeValueChanged();
                app.PRR_mode.Enable = 1;
                app.West_PRR_Pulse_rate.Value = 10;
                app.East_PRR_Pulse_rate.Value = 10;
            elseif value == "Exponential"
                app.West_PRR_nPulses.Value = 5;
                app.East_PRR_nPulses.Value = 5;
                app.West_PRR_PD_units.Value = "msec";
                app.West_PRR_PD.Value = 15;
                app.East_PRR_PD_units.Value = "msec";
                app.East_PRR_PD.Value = 15;
                app.West_PRR_RT_units.Value = "msec";
                app.West_PRR_RT.Value = app.West_PRR_PD.Value/10;
                app.East_PRR_RT_units.Value = "msec";
                app.East_PRR_RT.Value = app.East_PRR_PD.Value/10;
                app.West_PRR_RT_units.Enable = 0;
                app.West_PRR_RT.Enable = 0;
                app.East_PRR_RT_units.Enable = 0;
                app.East_PRR_RT.Enable=0;
                app.PRR_mode.Value = "Select";
                app.PRR_modeValueChanged();
                app.PRR_mode.Enable = 1;
                app.West_PRR_Pulse_rate.Value = 10;
                app.East_PRR_Pulse_rate.Value = 10;
            end
        end

        % Value changed function: West_PRR_Pulse_rate
        function West_PRR_Pulse_rateValueChanged(app, event)
            app.West_PRR_Gap_time.Value = (1/app.West_PRR_Pulse_rate.Value)*1e3;
        end

        % Value changed function: East_PRR_Pulse_rate
        function East_PRR_Pulse_rateValueChanged(app, event)
            app.East_PRR_Gap_time.Value = (1/app.East_PRR_Pulse_rate.Value)*1e3;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SearchTab = uitab(app.TabGroup);
            app.SearchTab.Title = 'Search';
            app.ControlPanel = uipanel(app.SearchTab);
            app.ControlPanel.Title = 'Control';
            app.CarrierFrequenciesHzPanel = uipanel(app.ControlPanel);
            app.CarrierFrequenciesHzPanel.Title = 'Carrier Frequencies (Hz)';
            app.Fc_check_1 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_1.ValueChangedFcn = createCallbackFcn(app, @Fc_check_1ValueChanged, true);
            app.Fc_check_1.Text = '';
            app.Fc_check_1.Value = true;
            app.Fc_1 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_1.Value = 150;
            app.Fc_2 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_2.Value = 300;
            app.Fc_check_2 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_2.ValueChangedFcn = createCallbackFcn(app, @Fc_check_2ValueChanged, true);
            app.Fc_check_2.Text = '';
            app.Fc_check_2.Value = true;
            app.Fc_3 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_3.Value = 600;
            app.Fc_check_3 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_3.ValueChangedFcn = createCallbackFcn(app, @Fc_check_3ValueChanged, true);
            app.Fc_check_3.Text = '';
            app.Fc_check_3.Value = true;
            app.Fc_4 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_4.Value = 1100;
            app.Fc_check_4 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_4.ValueChangedFcn = createCallbackFcn(app, @Fc_check_4ValueChanged, true);
            app.Fc_check_4.Text = '';
            app.Fc_check_4.Value = true;
            app.Fc_5 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_5.Value = 1600;
            app.Fc_check_5 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_5.ValueChangedFcn = createCallbackFcn(app, @Fc_check_5ValueChanged, true);
            app.Fc_check_5.Text = '';
            app.Fc_check_5.Value = true;
            app.ModulatorFrequenciesHzPanel = uipanel(app.ControlPanel);
            app.ModulatorFrequenciesHzPanel.Title = 'Modulator Frequencies (Hz)';
            app.Fm_check_1 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_1.ValueChangedFcn = createCallbackFcn(app, @Fm_check_1ValueChanged, true);
            app.Fm_check_1.Text = '';
            app.Fm_check_1.Value = true;
            app.Fm_1 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_1.Value = 10;
            app.Fm_2 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_2.Value = 20;
            app.Fm_check_2 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_2.ValueChangedFcn = createCallbackFcn(app, @Fm_check_2ValueChanged, true);
            app.Fm_check_2.Text = '';
            app.Fm_check_2.Value = true;
            app.Fm_3 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_3.Value = 40;
            app.Fm_check_3 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_3.ValueChangedFcn = createCallbackFcn(app, @Fm_check_3ValueChanged, true);
            app.Fm_check_3.Text = '';
            app.Fm_check_3.Value = true;
            app.Fm_4 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_4.Value = 60;
            app.Fm_check_4 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_4.ValueChangedFcn = createCallbackFcn(app, @Fm_check_4ValueChanged, true);
            app.Fm_check_4.Text = '';
            app.Fm_check_4.Value = true;
            app.Fm_5 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_5.Value = 80;
            app.Fm_check_5 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_5.ValueChangedFcn = createCallbackFcn(app, @Fm_check_5ValueChanged, true);
            app.Fm_check_5.Text = '';
            app.Fm_check_5.Value = true;
            app.ModeDropDownLabel = uilabel(app.ControlPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.ControlPanel);
            app.ModeDropDown.Items = {'Normal', 'Fixed Carrier', 'Fixed Modulation'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Normal';
            app.FrequencyCyclerHzPanel = uipanel(app.ControlPanel);
            app.FrequencyCyclerHzPanel.Title = 'Frequency Cycler (Hz)';
            app.StartEditFieldLabel = uilabel(app.FrequencyCyclerHzPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.Start_frequency = uieditfield(app.FrequencyCyclerHzPanel, 'numeric');
            app.Start_frequency.Value = 300;
            app.StepEditFieldLabel = uilabel(app.FrequencyCyclerHzPanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.Step_frequency = uieditfield(app.FrequencyCyclerHzPanel, 'numeric');
            app.Step_frequency.Value = 20;
            app.EndEditFieldLabel = uilabel(app.FrequencyCyclerHzPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.End_frequency = uieditfield(app.FrequencyCyclerHzPanel, 'numeric');
            app.End_frequency.Value = 600;
            app.StimulusDurationmsecLabel = uilabel(app.SearchTab);
            app.StimulusDurationmsecLabel.Text = 'Stimulus Duration (msec)';
            app.Search_stimulus_duration = uispinner(app.SearchTab);
            app.Search_stimulus_duration.Step = 50;
            app.Search_stimulus_duration.Value = 400;
            app.PRRTab = uitab(app.TabGroup);
            app.PRRTab.Title = 'PRR';
            app.ControlPanel_PRR = uipanel(app.PRRTab);
            app.ControlPanel_PRR.Title = 'Control';
            app.CarrierFrequenciesHzPanel_2 = uipanel(app.ControlPanel_PRR);
            app.CarrierFrequenciesHzPanel_2.Title = 'Carrier Frequencies (Hz)';
            app.Fc_PRR_check_1 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_1.ValueChangedFcn = createCallbackFcn(app, @Fc_PRR_check_1ValueChanged, true);
            app.Fc_PRR_check_1.Text = '';
            app.Fc_PRR_1 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_1.Enable = 'off';
            app.Fc_PRR_1.Value = 150;
            app.Fc_PRR_2 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_2.Enable = 'off';
            app.Fc_PRR_2.Value = 300;
            app.Fc_PRR_check_2 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_2.ValueChangedFcn = createCallbackFcn(app, @Fc_PRR_check_2ValueChanged, true);
            app.Fc_PRR_check_2.Text = '';
            app.Fc_PRR_3 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_3.Enable = 'off';
            app.Fc_PRR_3.Value = 600;
            app.Fc_PRR_check_3 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_3.ValueChangedFcn = createCallbackFcn(app, @Fc_PRR_check_3ValueChanged, true);
            app.Fc_PRR_check_3.Text = '';
            app.Fc_PRR_4 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_4.Enable = 'off';
            app.Fc_PRR_4.Value = 1100;
            app.Fc_PRR_check_4 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_4.ValueChangedFcn = createCallbackFcn(app, @Fc_PRR_check_4ValueChanged, true);
            app.Fc_PRR_check_4.Text = '';
            app.Fc_PRR_5 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_5.Value = 1600;
            app.Fc_PRR_check_5 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_5.ValueChangedFcn = createCallbackFcn(app, @Fc_PRR_check_5ValueChanged, true);
            app.Fc_PRR_check_5.Text = '';
            app.Fc_PRR_check_5.Value = true;
            app.EnergyPanel = uipanel(app.ControlPanel_PRR);
            app.EnergyPanel.Title = 'Energy';
            app.Ec_PRR_1 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_1.Enable = 'off';
            app.Ec_PRR_2 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_2.Enable = 'off';
            app.Ec_PRR_3 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_3.Enable = 'off';
            app.Ec_PRR_4 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_4.Enable = 'off';
            app.Ec_PRR_5 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_5.Value = 1;
            app.PRRppsSelPanel = uipanel(app.ControlPanel_PRR);
            app.PRRppsSelPanel.Title = 'PRR (pps): Sel';
            app.Fm_PRR_check_1 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_1.ValueChangedFcn = createCallbackFcn(app, @Fm_PRR_check_1ValueChanged, true);
            app.Fm_PRR_check_1.Text = '';
            app.Fm_PRR_check_1.Value = true;
            app.Fm_PRR_1 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_1.Value = 5;
            app.Fm_PRR_2 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_2.Value = 10;
            app.Fm_PRR_check_2 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_2.ValueChangedFcn = createCallbackFcn(app, @Fm_PRR_check_2ValueChanged, true);
            app.Fm_PRR_check_2.Text = '';
            app.Fm_PRR_check_2.Value = true;
            app.Fm_PRR_3 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_3.Value = 20;
            app.Fm_PRR_check_3 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_3.ValueChangedFcn = createCallbackFcn(app, @Fm_PRR_check_3ValueChanged, true);
            app.Fm_PRR_check_3.Text = '';
            app.Fm_PRR_check_3.Value = true;
            app.Fm_PRR_4 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_4.Value = 40;
            app.Fm_PRR_check_4 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_4.ValueChangedFcn = createCallbackFcn(app, @Fm_PRR_check_4ValueChanged, true);
            app.Fm_PRR_check_4.Text = '';
            app.Fm_PRR_check_4.Value = true;
            app.Fm_PRR_5 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_5.Value = 60;
            app.Fm_PRR_check_5 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_5.ValueChangedFcn = createCallbackFcn(app, @Fm_PRR_check_5ValueChanged, true);
            app.Fm_PRR_check_5.Text = '';
            app.Fm_PRR_check_5.Value = true;
            app.PRRModeDropDownLabel = uilabel(app.ControlPanel_PRR);
            app.PRRModeDropDownLabel.Text = 'PRR Mode';
            app.PRR_mode = uidropdown(app.ControlPanel_PRR);
            app.PRR_mode.Items = {'Select', 'Cycle', 'Independent'};
            app.PRR_mode.ValueChangedFcn = createCallbackFcn(app, @PRR_modeValueChanged, true);
            app.PRR_mode.Value = 'Select';
            app.PRRCyclerppsCyclePanel = uipanel(app.ControlPanel_PRR);
            app.PRRCyclerppsCyclePanel.Title = 'PRR Cycler (pps): Cycle';
            app.StartEditFieldLabel_2 = uilabel(app.PRRCyclerppsCyclePanel);
            app.StartEditFieldLabel_2.Enable = 'off';
            app.StartEditFieldLabel_2.Text = 'Start';
            app.Fm_PRR_start = uieditfield(app.PRRCyclerppsCyclePanel, 'numeric');
            app.Fm_PRR_start.Enable = 'off';
            app.Fm_PRR_start.Value = 10;
            app.StepEditFieldLabel_2 = uilabel(app.PRRCyclerppsCyclePanel);
            app.StepEditFieldLabel_2.Enable = 'off';
            app.StepEditFieldLabel_2.Text = 'Step';
            app.Fm_PRR_step = uieditfield(app.PRRCyclerppsCyclePanel, 'numeric');
            app.Fm_PRR_step.Enable = 'off';
            app.Fm_PRR_step.Value = 10;
            app.EndEditFieldLabel_2 = uilabel(app.PRRCyclerppsCyclePanel);
            app.EndEditFieldLabel_2.Enable = 'off';
            app.EndEditFieldLabel_2.Text = 'End';
            app.Fm_PRR_end = uieditfield(app.PRRCyclerppsCyclePanel, 'numeric');
            app.Fm_PRR_end.Enable = 'off';
            app.Fm_PRR_end.Value = 60;
            app.EnvelopeDropDownLabel = uilabel(app.ControlPanel_PRR);
            app.EnvelopeDropDownLabel.Text = 'Envelope';
            app.PRREnvelope = uidropdown(app.ControlPanel_PRR);
            app.PRREnvelope.Items = {'Triangular', 'Exponential', 'Rectangular'};
            app.PRREnvelope.ValueChangedFcn = createCallbackFcn(app, @PRREnvelopeValueChanged, true);
            app.PRREnvelope.Value = 'Triangular';
            app.EastPanel = uipanel(app.PRRTab);
            app.EastPanel.Title = 'East';
            app.nPulsesLabel = uilabel(app.EastPanel);
            app.nPulsesLabel.Text = 'nPulses';
            app.East_PRR_nPulses = uieditfield(app.EastPanel, 'numeric');
            app.East_PRR_nPulses.Value = 5;
            app.PDLabel = uilabel(app.EastPanel);
            app.PDLabel.Text = 'PD';
            app.East_PRR_PD = uieditfield(app.EastPanel, 'numeric');
            app.East_PRR_PD.Value = 15;
            app.East_PRR_PD_units = uidropdown(app.EastPanel);
            app.East_PRR_PD_units.Items = {'msec', '%'};
            app.East_PRR_PD_units.Value = 'msec';
            app.East_PRR_RT_units = uidropdown(app.EastPanel);
            app.East_PRR_RT_units.Items = {'msec', '%'};
            app.East_PRR_RT_units.Value = '%';
            app.RTLabel = uilabel(app.EastPanel);
            app.RTLabel.Text = 'RT';
            app.East_PRR_RT = uieditfield(app.EastPanel, 'numeric');
            app.East_PRR_RT.Value = 20;
            app.PulserateppsLabel = uilabel(app.EastPanel);
            app.PulserateppsLabel.Text = 'Pulse rate (pps)';
            app.East_PRR_Pulse_rate = uieditfield(app.EastPanel, 'numeric');
            app.East_PRR_Pulse_rate.ValueChangedFcn = createCallbackFcn(app, @East_PRR_Pulse_rateValueChanged, true);
            app.East_PRR_Pulse_rate.Enable = 'off';
            app.East_PRR_Pulse_rate.Value = 10;
            app.GapafterpulseLabel = uilabel(app.EastPanel);
            app.GapafterpulseLabel.Text = 'Gap after pulse';
            app.East_PRR_Gap_after_pulse = uieditfield(app.EastPanel, 'numeric');
            app.East_PRR_Gap_after_pulse.Enable = 'off';
            app.GaptimemsecLabel = uilabel(app.EastPanel);
            app.GaptimemsecLabel.Text = 'Gap time (msec)';
            app.East_PRR_Gap_time = uieditfield(app.EastPanel, 'numeric');
            app.East_PRR_Gap_time.Enable = 'off';
            app.East_PRR_Gap_time.Value = 10;
            app.East_PRR_nPulses_check = uicheckbox(app.EastPanel);
            app.East_PRR_nPulses_check.ValueChangedFcn = createCallbackFcn(app, @East_PRR_nPulses_checkValueChanged, true);
            app.East_PRR_nPulses_check.Text = '';
            app.East_PRR_nPulses_check.Value = true;
            app.WestPanel = uipanel(app.PRRTab);
            app.WestPanel.Title = 'West';
            app.nPulsesLabel_2 = uilabel(app.WestPanel);
            app.nPulsesLabel_2.Text = 'nPulses';
            app.West_PRR_nPulses = uieditfield(app.WestPanel, 'numeric');
            app.West_PRR_nPulses.Value = 5;
            app.PDLabel_2 = uilabel(app.WestPanel);
            app.PDLabel_2.Text = 'PD';
            app.West_PRR_PD = uieditfield(app.WestPanel, 'numeric');
            app.West_PRR_PD.Value = 15;
            app.West_PRR_PD_units = uidropdown(app.WestPanel);
            app.West_PRR_PD_units.Items = {'msec', '%'};
            app.West_PRR_PD_units.Value = 'msec';
            app.West_PRR_RT_units = uidropdown(app.WestPanel);
            app.West_PRR_RT_units.Items = {'msec', '%'};
            app.West_PRR_RT_units.Value = '%';
            app.RTLabel_2 = uilabel(app.WestPanel);
            app.RTLabel_2.Text = 'RT';
            app.West_PRR_RT = uieditfield(app.WestPanel, 'numeric');
            app.West_PRR_RT.Value = 80;
            app.PulserateppsLabel_2 = uilabel(app.WestPanel);
            app.PulserateppsLabel_2.Text = 'Pulse rate (pps)';
            app.West_PRR_Pulse_rate = uieditfield(app.WestPanel, 'numeric');
            app.West_PRR_Pulse_rate.ValueChangedFcn = createCallbackFcn(app, @West_PRR_Pulse_rateValueChanged, true);
            app.West_PRR_Pulse_rate.Enable = 'off';
            app.West_PRR_Pulse_rate.Value = 10;
            app.GapafterpulseLabel_2 = uilabel(app.WestPanel);
            app.GapafterpulseLabel_2.Text = 'Gap after pulse';
            app.West_PRR_Gap_after_pulse = uieditfield(app.WestPanel, 'numeric');
            app.West_PRR_Gap_after_pulse.Enable = 'off';
            app.GaptimemsecLabel_2 = uilabel(app.WestPanel);
            app.GaptimemsecLabel_2.Text = 'Gap time (msec)';
            app.West_PRR_Gap_time = uieditfield(app.WestPanel, 'numeric');
            app.West_PRR_Gap_time.Enable = 'off';
            app.West_PRR_Gap_time.Value = 10;
            app.West_PRR_nPulses_check = uicheckbox(app.WestPanel);
            app.West_PRR_nPulses_check.ValueChangedFcn = createCallbackFcn(app, @West_PRR_nPulses_checkValueChanged, true);
            app.West_PRR_nPulses_check.Text = '';
            app.West_PRR_nPulses_check.Value = true;
            app.NaturalTab = uitab(app.TabGroup);
            app.NaturalTab.Title = 'Natural';
            app.ControlPanel_Natural = uipanel(app.NaturalTab);
            app.ControlPanel_Natural.Title = 'Control';
            app.PlayModeLabel = uilabel(app.ControlPanel_Natural);
            app.PlayModeLabel.Text = 'Play Mode';
            app.NaturalPlayMode = uidropdown(app.ControlPanel_Natural);
            app.NaturalPlayMode.Items = {'Normal', 'Reversed'};
            app.NaturalPlayMode.Value = 'Normal';
            app.CallDropDownLabel = uilabel(app.ControlPanel_Natural);
            app.CallDropDownLabel.Text = 'Call';
            app.NaturalCall = uidropdown(app.ControlPanel_Natural);
            app.NaturalCall.Items = {};
            app.NaturalCall.Value = {};
            app.SeminaturalTab = uitab(app.TabGroup);
            app.SeminaturalTab.Title = 'Semi-natural';
            app.CallDropDownLabel_2 = uilabel(app.SeminaturalTab);
            app.CallDropDownLabel_2.Text = 'Call';
            app.NaturalCall_2 = uidropdown(app.SeminaturalTab);
            app.NaturalCall_2.Items = {};
            app.NaturalCall_2.Value = {};
            app.ControlPanel_2 = uipanel(app.SeminaturalTab);
            app.ControlPanel_2.Title = 'Control';
            app.PRRppsSelPanel_2 = uipanel(app.ControlPanel_2);
            app.PRRppsSelPanel_2.Title = 'PRR (pps): Sel';
            app.Fm_PRR_check_6 = uicheckbox(app.PRRppsSelPanel_2);
            app.Fm_PRR_check_6.Text = '';
            app.Fm_PRR_check_6.Value = true;
            app.Fm_PRR_6 = uieditfield(app.PRRppsSelPanel_2, 'numeric');
            app.Fm_PRR_6.Value = 5;
            app.Fm_PRR_7 = uieditfield(app.PRRppsSelPanel_2, 'numeric');
            app.Fm_PRR_7.Value = 10;
            app.Fm_PRR_check_7 = uicheckbox(app.PRRppsSelPanel_2);
            app.Fm_PRR_check_7.Text = '';
            app.Fm_PRR_check_7.Value = true;
            app.Fm_PRR_8 = uieditfield(app.PRRppsSelPanel_2, 'numeric');
            app.Fm_PRR_8.Value = 20;
            app.Fm_PRR_check_8 = uicheckbox(app.PRRppsSelPanel_2);
            app.Fm_PRR_check_8.Text = '';
            app.Fm_PRR_check_8.Value = true;
            app.Fm_PRR_9 = uieditfield(app.PRRppsSelPanel_2, 'numeric');
            app.Fm_PRR_9.Value = 40;
            app.Fm_PRR_check_9 = uicheckbox(app.PRRppsSelPanel_2);
            app.Fm_PRR_check_9.Text = '';
            app.Fm_PRR_check_9.Value = true;
            app.Fm_PRR_10 = uieditfield(app.PRRppsSelPanel_2, 'numeric');
            app.Fm_PRR_10.Value = 60;
            app.Fm_PRR_check_10 = uicheckbox(app.PRRppsSelPanel_2);
            app.Fm_PRR_check_10.Text = '';
            app.Fm_PRR_check_10.Value = true;
            app.PlayModeLabel_2 = uilabel(app.ControlPanel_2);
            app.PlayModeLabel_2.Text = 'Play Mode';
            app.NaturalPlayMode_2 = uidropdown(app.ControlPanel_2);
            app.NaturalPlayMode_2.Items = {'Normal', 'Reversed'};
            app.NaturalPlayMode_2.Value = 'Normal';
            app.Speaker = uiknob(app.UIFigure, 'discrete');
            app.Speaker.Items = {'East', 'Biaural', 'West'};
            app.Speaker.ValueChangedFcn = createCallbackFcn(app, @SpeakerValueChanged, true);
            app.Speaker.Value = 'West';
            app.ControlSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValueChanged, true);
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.ConnectionLampLabel = uilabel(app.StatusPanel);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.StatusPanel);
            app.LoadCircuitLampLabel = uilabel(app.StatusPanel);
            app.LoadCircuitLampLabel.Text = 'Load Circuit';
            app.LoadCircuitLamp = uilamp(app.StatusPanel);
            app.LoadParametersLampLabel = uilabel(app.StatusPanel);
            app.LoadParametersLampLabel.Text = 'Load Parameters';
            app.LoadParametersLamp = uilamp(app.StatusPanel);
            app.WestSpeakerPanel = uipanel(app.UIFigure);
            app.WestSpeakerPanel.Title = 'West Speaker';
            app.West_plot_type = uiswitch(app.WestSpeakerPanel, 'rocker');
            app.West_plot_type.Items = {'Time', 'FFT'};
            app.West_plot_type.Value = 'Time';
            app.West_current_carrier = uieditfield(app.WestSpeakerPanel, 'numeric');
            app.West_current_modulator = uieditfield(app.WestSpeakerPanel, 'numeric');
            app.West_modulator_plot = uiaxes(app.WestSpeakerPanel);
            title(app.West_modulator_plot, 'Modulator')
            xlabel(app.West_modulator_plot, 'time (sec)')
            ylabel(app.West_modulator_plot, 'Amplitude (V)')
            zlabel(app.West_modulator_plot, 'Z')
            app.West_carrier_plot = uiaxes(app.WestSpeakerPanel);
            title(app.West_carrier_plot, 'Carrier')
            xlabel(app.West_carrier_plot, 'time (sec)')
            ylabel(app.West_carrier_plot, 'Amplitude (V)')
            zlabel(app.West_carrier_plot, 'Z')
            app.West_stimulus_plot = uiaxes(app.WestSpeakerPanel);
            title(app.West_stimulus_plot, 'Stimulus')
            xlabel(app.West_stimulus_plot, 'time (sec)')
            ylabel(app.West_stimulus_plot, 'Amplitude (V)')
            zlabel(app.West_stimulus_plot, 'Z')
            app.EastSpeakerPanel = uipanel(app.UIFigure);
            app.EastSpeakerPanel.Title = 'East Speaker';
            app.East_plot_type = uiswitch(app.EastSpeakerPanel, 'rocker');
            app.East_plot_type.Items = {'Time', 'FFT'};
            app.East_plot_type.Value = 'Time';
            app.East_current_carrier = uieditfield(app.EastSpeakerPanel, 'numeric');
            app.East_current_modulator = uieditfield(app.EastSpeakerPanel, 'numeric');
            app.East_modulator_plot = uiaxes(app.EastSpeakerPanel);
            title(app.East_modulator_plot, 'Modulator')
            xlabel(app.East_modulator_plot, 'time (sec)')
            ylabel(app.East_modulator_plot, 'Amplitude (V)')
            zlabel(app.East_modulator_plot, 'Z')
            app.East_carrier_plot = uiaxes(app.EastSpeakerPanel);
            title(app.East_carrier_plot, 'Carrier')
            xlabel(app.East_carrier_plot, 'time (sec)')
            ylabel(app.East_carrier_plot, 'Amplitude (V)')
            zlabel(app.East_carrier_plot, 'Z')
            app.East_stimulus_plot = uiaxes(app.EastSpeakerPanel);
            title(app.East_stimulus_plot, 'Stimulus')
            xlabel(app.East_stimulus_plot, 'time (sec)')
            ylabel(app.East_stimulus_plot, 'Amplitude (V)')
            zlabel(app.East_stimulus_plot, 'Z')
            app.InterStimulusIntervalsecLabel = uilabel(app.UIFigure);
            app.InterStimulusIntervalsecLabel.Text = 'Inter-Stimulus Interval (sec)';
            app.ISI = uispinner(app.UIFigure);
            app.ISI.Step = 0.5;
            app.ISI.Value = 2;
            app.InterSpeakerIntervalmsecLabel = uilabel(app.UIFigure);
            app.InterSpeakerIntervalmsecLabel.Text = 'Inter-Speaker Interval (msec)';
            app.Inter_speaker_interval = uispinner(app.UIFigure);
            app.Inter_speaker_interval.Step = 5;
            app.SpeciesDropDownLabel = uilabel(app.UIFigure);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.UIFigure);
            app.SpeciesDropDown.Items = {'Rana pipiens', 'Hyla versicolor', 'Hyla chrysocelis', 'Pseudacris feriarum'};
            app.SpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeciesDropDownValueChanged, true);
            app.SpeciesDropDown.Value = 'Rana pipiens';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stimulus(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end