classdef stimulusV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel_7                        matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        EastAttenuation                matlab.ui.control.Spinner
        WestAttenuation                matlab.ui.control.Spinner
        EastattndBLabel                matlab.ui.control.Label
        WestattndBLabel                matlab.ui.control.Label
        WestAttenuationSlider          matlab.ui.control.Slider
        EastAttenuationSlider          matlab.ui.control.Slider
        WestCarrierPlot                matlab.ui.control.UIAxes
        WestModulatorPlot              matlab.ui.control.UIAxes
        WestStimulusPlot               matlab.ui.control.UIAxes
        EastCarrierPlot                matlab.ui.control.UIAxes
        EastModulatorPlot              matlab.ui.control.UIAxes
        EastStimulusPlot               matlab.ui.control.UIAxes
        ParadigmTabGroup               matlab.ui.container.TabGroup
        SearchTab                      matlab.ui.container.Tab
        SearchTabLayout                matlab.ui.container.GridLayout
        SearchModulatorPanel           matlab.ui.container.Panel
        SearchModulatorLayout          matlab.ui.container.GridLayout
        Panel_15                       matlab.ui.container.Panel
        GridLayout14_8                 matlab.ui.container.GridLayout
        EnergyLabel_4                  matlab.ui.control.Label
        FrequencyHzLabel_4             matlab.ui.control.Label
        Panel_16                       matlab.ui.container.Panel
        GridLayout14_9                 matlab.ui.container.GridLayout
        Spinner_42                     matlab.ui.control.Spinner
        Spinner_41                     matlab.ui.control.Spinner
        CheckBox_7                     matlab.ui.control.CheckBox
        Panel_17                       matlab.ui.container.Panel
        GridLayout14_10                matlab.ui.container.GridLayout
        Spinner_44                     matlab.ui.control.Spinner
        Spinner_43                     matlab.ui.control.Spinner
        CheckBox_8                     matlab.ui.control.CheckBox
        Panel_18                       matlab.ui.container.Panel
        GridLayout14_11                matlab.ui.container.GridLayout
        Spinner_46                     matlab.ui.control.Spinner
        Spinner_45                     matlab.ui.control.Spinner
        CheckBox_9                     matlab.ui.control.CheckBox
        Panel_19                       matlab.ui.container.Panel
        GridLayout14_12                matlab.ui.container.GridLayout
        Spinner_48                     matlab.ui.control.Spinner
        Spinner_47                     matlab.ui.control.Spinner
        CheckBox_10                    matlab.ui.control.CheckBox
        Panel_20                       matlab.ui.container.Panel
        GridLayout14_13                matlab.ui.container.GridLayout
        Spinner_50                     matlab.ui.control.Spinner
        Spinner_49                     matlab.ui.control.Spinner
        CheckBox_11                    matlab.ui.control.CheckBox
        Panel_21                       matlab.ui.container.Panel
        GridLayout14_14                matlab.ui.container.GridLayout
        Spinner_52                     matlab.ui.control.Spinner
        Spinner_51                     matlab.ui.control.Spinner
        CheckBox_12                    matlab.ui.control.CheckBox
        SearchCyclerPanel              matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        EndLabel                       matlab.ui.control.Label
        StepLabel                      matlab.ui.control.Label
        StartLabel                     matlab.ui.control.Label
        SearchEnd                      matlab.ui.control.Spinner
        SearchStep                     matlab.ui.control.Spinner
        SearchStart                    matlab.ui.control.Spinner
        SearchModePanel                matlab.ui.container.Panel
        SearchMode                     matlab.ui.control.DiscreteKnob
        SearchCarrierPanel             matlab.ui.container.Panel
        SearchCarrierLayout            matlab.ui.container.GridLayout
        Panel_8                        matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        EnergyLabel_3                  matlab.ui.control.Label
        FrequencyHzLabel_3             matlab.ui.control.Label
        Panel_9                        matlab.ui.container.Panel
        GridLayout14_2                 matlab.ui.container.GridLayout
        Spinner_30                     matlab.ui.control.Spinner
        Spinner_29                     matlab.ui.control.Spinner
        CheckBox                       matlab.ui.control.CheckBox
        Panel_10                       matlab.ui.container.Panel
        GridLayout14_3                 matlab.ui.container.GridLayout
        Spinner_32                     matlab.ui.control.Spinner
        Spinner_31                     matlab.ui.control.Spinner
        CheckBox_2                     matlab.ui.control.CheckBox
        Panel_11                       matlab.ui.container.Panel
        GridLayout14_4                 matlab.ui.container.GridLayout
        Spinner_34                     matlab.ui.control.Spinner
        Spinner_33                     matlab.ui.control.Spinner
        CheckBox_3                     matlab.ui.control.CheckBox
        Panel_12                       matlab.ui.container.Panel
        GridLayout14_5                 matlab.ui.container.GridLayout
        Spinner_36                     matlab.ui.control.Spinner
        Spinner_35                     matlab.ui.control.Spinner
        CheckBox_4                     matlab.ui.control.CheckBox
        Panel_13                       matlab.ui.container.Panel
        GridLayout14_6                 matlab.ui.container.GridLayout
        Spinner_38                     matlab.ui.control.Spinner
        Spinner_37                     matlab.ui.control.Spinner
        CheckBox_5                     matlab.ui.control.CheckBox
        Panel_14                       matlab.ui.container.Panel
        GridLayout14_7                 matlab.ui.container.GridLayout
        Spinner_40                     matlab.ui.control.Spinner
        Spinner_39                     matlab.ui.control.Spinner
        CheckBox_6                     matlab.ui.control.CheckBox
        Tab2                           matlab.ui.container.Tab
        SystemPanel                    matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_3                        matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        Species                        matlab.ui.control.DropDown
        SpeciesDropDownLabel           matlab.ui.control.Label
        TDTStatusPanel                 matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        LoadParamsLamp                 matlab.ui.control.Lamp
        LoadParamsLampLabel            matlab.ui.control.Label
        LoadCircuitLamp                matlab.ui.control.Lamp
        LoadCircuitLampLabel           matlab.ui.control.Label
        ConnectionLamp                 matlab.ui.control.Lamp
        ConnectionLampLabel            matlab.ui.control.Label
        MasterSwitch                   matlab.ui.control.Button
        Panel_4                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        InterStimulusIntervalSlider    matlab.ui.control.Slider
        StimulusDuration               matlab.ui.control.NumericEditField
        StimulusDurationmsecEditFieldLabel  matlab.ui.control.Label
        InterStimulusIntervalsecLabel  matlab.ui.control.Label
        InterStimulusIntervalEditBox   matlab.ui.control.NumericEditField
        Panel_5                        matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        InterSpeakerIntervalSlider     matlab.ui.control.Slider
        Speaker                        matlab.ui.control.DiscreteKnob
        InterSpeakerIntervalmsecLabel  matlab.ui.control.Label
        InterSpeakerIntervalEditBox    matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        MasterState % Description.
        fs = 24144.0625; % Sampling Rate of TDT System III (Hz).
        BaseAttenuation = -27; % Default Attenuation Setting on the TDT PA (dB).
        RPcontroller;
    end

    methods (Access = private)
        %% Hardware Interaction Methods:
        function hardware_initialization(app)
            app.RPcontroller = actxserver('RPco.x');
            if app.RPcontroller.ConnectRP2('GB', 1)
                app.ConnectionLamp.Color = [0, 1, 0];
            else
                app.ConnectionLamp.Color = [1, 0, 0];
            end
        end
        
        function e = load_circuit(app, circuit)
            e = app.RPcontroller.LoadCOF(circuit);
            if e
                app.LoadCircuitLamp.Color = [0, 1, 0];
            else
                app.LoadCircuitLamp.Color = [1, 0, 0];
            end
        end
        function e = transfer_parameters(app, west, east)
            e = zeros(8, 1);
            e(1) = app.RPcontroller.WriteTagV('EastPulseTrigger', 0, east.pulseTriggers);
            e(2) = app.RPcontroller.WriteTagV('EastStimulusTrigger', 0, east.stimulusTriggers);
            e(3) = app.RPcontroller.WriteTagV('EastStimulus', 0, east.stimulus);
            e(4) = app.RPcontroller.SetTagVal('EastStimulusPoints', size(east.stimulus, 2));
            e(5) = app.RPcontroller.WriteTagV('WestPulseTrigger', 0, west.pulseTriggers);
            e(6) = app.RPcontroller.WriteTagV('WestStimulusTrigger', 0, west.stimulusTriggers);
            e(7) = app.RPcontroller.WriteTagV('WestStimulus', 0, west.stimulus);
            e(8) = app.RPcontroller.SetTagVal('WestStimulusPoints', size(west.stimulus, 2));
            e = prod(e, 'all');
            if e
                app.LoadParamsLamp.Color = [0, 1, 0];
            else
                app.LoadParamsLamp.Color = [1, 0, 0];
            end
        end
        
        %% Helper Methods:
        function y = attenuateRelativeToBase(app, x, dB)
            y = x.*10.^((dB-app.BaseAttenuation)./20);
        end
        
        function y = attenuate(~, x, dB)
            y = x.*10.^(dB./20);
        end
        
        function y = appendSamplesAtStart(~, x, samples)
           y = cat(2, zeros(size(x, 1), samples), x); 
        end
        
        function y = appendSamplesAtEnd(~, x, samples)
            y = cat(2, x, zeros(size(x, 1), samples));
        end
        
        function speaker = padAtStart(app, speaker, samples)
            speaker.carrier = app.appendSamplesAtStart(speaker.carrier, samples);
            speaker.modulator = app.appendSamplesAtStart(speaker.modulator, samples);
            speaker.stimulus = app.appendSamplesAtStart(speaker.stimulus, samples);
            speaker.stimulusTriggers = app.appendSamplesAtStart(speaker.stimulusTriggers, samples);
            speaker.pulseTriggers = app.appendSamplesAtStart(speaker.pulseTriggers, samples);
        end
        
        function speaker = padAtEnd(app, speaker, samples)
            speaker.carrier = app.appendSamplesAtEnd(speaker.carrier, samples);
            speaker.modulator = app.appendSamplesAtEnd(speaker.modulator, samples);
            speaker.stimulus = app.appendSamplesAtEnd(speaker.stimulus, samples);
            speaker.stimulusTriggers = app.appendSamplesAtEnd(speaker.stimulusTriggers, samples);
            speaker.pulseTriggers = app.appendSamplesAtEnd(speaker.pulseTriggers, samples);
        end
        
        %% Search Stimulus Methods:
        function carriers = getSearchCarriers(app)
            carriers = {};
            if app.SearchMode.Value == "Normal" || app.SearchMode.Value == "Fixed Carrier"
                editboxes = app.SearchCarrierPanel.findobj('Tag', 'frequency').findobj('Enable', 1);
                carriers.frequency = zeros(size(editboxes));
                carriers.energy = zeros(size(editboxes));
                for i = 1: 1: size(editboxes, 1)
                    carriers.frequency(i) = editboxes(i).Value;
                    carriers.energy(i) = editboxes(i).Parent.findobj('Tag', 'energy').Value;
                end
                carriers.frequency = flipud(carriers.frequency);
                carriers.energy = flipud(carriers.energy);
            elseif app.SearchMode.Value == "Fixed Modulation"
                carriers.frequency = (app.SearchStart.Value: app.SearchStep.Value: app.SearchEnd.Value)';
                carriers.energy = ones(size(carriers.frequency));
            end
        end

        function modulators = getSearchModulators(app)
            modulators = {};
            if app.SearchMode.Value == "Normal" || app.SearchMode.Value == "Fixed Modulation"
                editboxes = app.SearchModulatorPanel.findobj('Tag', 'frequency').findobj('Enable', 1);
                modulators.frequency = zeros(size(editboxes));
                modulators.energy = zeros(size(editboxes));
                for i = 1: 1: size(editboxes, 1)
                    modulators.frequency(i) = editboxes(i).Value;
                    modulators.energy(i) = editboxes(i).Parent.findobj('Tag', 'energy').Value;
                end
                modulators.frequency = flipud(modulators.frequency);
                modulators.energy = flipud(modulators.energy);
            elseif app.SearchMode.Value == "Fixed Carrier"
                modulators.frequency = (app.SearchStart.Value: app.SearchStep.Value: app.SearchEnd.Value)';
                modulators.energy = ones(size(modulators.frequency));
            end
        end

        function [carriers, modulators, stimuli] = buildSearchStimulus(app)
            carriers = app.getSearchCarriers();
            modulators = app.getSearchModulators();
            stimuli.times = 0: (1/app.fs): (app.StimulusDuration.Value*1e-3) - (1/app.fs);
            carriers.waveform = sin(2*pi.*carriers.frequency*stimuli.times);
            modulators.waveform = 0.5*sin(2*pi.*modulators.frequency*stimuli.times - pi/2)+0.5;
            carriers.waveform = reshape(carriers.waveform, [size(carriers.waveform, 1), 1, size(carriers.waveform, 2)]);
            modulators.waveform = reshape(modulators.waveform, [1, size(modulators.waveform, 1), size(modulators.waveform, 2)]);
            stimuli.waveform = 10.*pagemtimes(carriers.waveform, modulators.waveform);
            stimuli.triggers = zeros(size(stimuli.waveform));
            stimuli.triggers(:, :, 1) = 1;
        end

        function search_stimulus_out(app)
            [carriers, modulators, stimuli] = app.buildSearchStimulus();
            while 1
                for i = 1: 1: size(stimuli.waveform, 1)
                    for j = 1: 1: size(stimuli.waveform, 2)
                        tstart = tic();
                        carrier = squeeze(carriers.waveform(i, 1, :))';
                        modulator = squeeze(modulators.waveform(1, j, :))';
                        stimulus = squeeze(stimuli.waveform(i, j, :))';
                        triggers = squeeze(stimuli.triggers(i, j, :))';
                        west.carrier = carrier;
                        west.modulator = modulator;
                        west.stimulus = stimulus;
                        west.stimulusTriggers = triggers;
                        west.pulseTriggers = triggers;
                        east.carrier = carrier;
                        east.modulator = modulator;
                        east.stimulus = stimulus;
                        east.stimulusTriggers = triggers;
                        east.pulseTriggers = triggers;
                        if strcmp(app.Speaker.Value, "West")
                            east.carrier = east.carrier.*0;
                            east.modulator = east.modulator.*0;
                            east.stimulus = east.stimulus.*0;
                            east.stimulusTriggers = east.stimulusTriggers.*0;
                            east.pulseTriggers = east.pulseTriggers.*0;
                        elseif strcmp(app.Speaker.Value, "East")
                            west.carrier = west.carrier.*0;
                            west.modulator = west.modulator.*0;
                            west.stimulus = west.stimulus.*0;
                            west.stimulusTriggers = west.stimulusTriggers.*0;
                            west.pulseTriggers = west.pulseTriggers.*0;
                        elseif strcmp(app.Speaker.Value, "Biaural")
                            append_samples = abs(floor(app.InterSpeakerIntervalEditBox.Value*1e-3*app.fs));
                            if app.InterSpeakerIntervalEditBox.Value > 0
                                west = app.padAtEnd(west, append_samples);
                                east = app.padAtStart(east, append_samples);
                            else
                                east = app.padAtEnd(east, append_samples);
                                west = app.padAtStart(west, append_samples);
                            end
                        end
                        app.RPcontroller.ClearCOF();
                        if app.load_circuit("biaural_circuit.rcx") == 0
                            error("Cannot load circuit.");
                        end
                        times = linspace(0, (app.StimulusDuration.Value+app.InterSpeakerIntervalEditBox.Value)*1e-3, size(east.stimulus, 2));
                        west.stimulus = app.attenuateRelativeToBase(west.stimulus, app.WestAttenuation.Value);
                        east.stimulus = app.attenuateRelativeToBase(east.stimulus, app.EastAttenuation.Value);
                        if app.transfer_parameters(west, east) == 0
                            error("Cannot load buffers and parameters.");
                        end
                        app.RPcontroller.Run();
                        app.RPcontroller.SoftTrg(1);
                        app.RPcontroller.SoftTrg(2);
                        plot(app.WestCarrierPlot, west.carrier, 'LineWidth', 0.5, "XData", times);
                        plot(app.WestModulatorPlot, west.modulator, 'LineWidth', 0.5, "XData", times);
                        plot(app.WestStimulusPlot, app.attenuate(west.stimulus, app.BaseAttenuation), 'LineWidth', 0.5, "XData", times);
                        plot(app.EastCarrierPlot, east.carrier, 'LineWidth', 0.5, "XData", times);
                        plot(app.EastModulatorPlot, east.modulator, 'LineWidth', 0.5, "XData", times);
                        plot(app.EastStimulusPlot, app.attenuate(east.stimulus, app.BaseAttenuation), 'LineWidth', 0.5, "XData", times);
                        if app.MasterState == 1
                            return;
                        end
                        pause(app.InterStimulusIntervalEditBox.Value - toc(tstart));
                    end
                end
            end
        end

        %% App methods:
        function sound_out(app)
            app.MasterState = 0;
            if app.ParadigmTabGroup.SelectedTab == app.SearchTab
                app.search_stimulus_out();
            elseif app.ParadigmTabGroup.SelectedTab == app.PRRTab
                app.prr_stimulus_out();
            elseif app.ParadigmTabGroup.SelectedTab == app.NaturalTab
                app.natural_stimulus_out();
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InterSpeakerIntervalEditBox.Limits = app.InterSpeakerIntervalSlider.Limits;
            app.InterStimulusIntervalEditBox.Limits = app.InterStimulusIntervalSlider.Limits;
            
            app.hardware_initialization();
        end

        % Value changed function: InterStimulusIntervalSlider
        function InterStimulusIntervalSliderValueChanged(app, event)
            app.InterStimulusIntervalEditBox.Value = app.InterStimulusIntervalSlider.Value;
        end

        % Value changed function: InterStimulusIntervalEditBox
        function InterStimulusIntervalEditBoxValueChanged(app, event)
            app.InterStimulusIntervalSlider.Value = app.InterStimulusIntervalEditBox.Value;
        end

        % Value changed function: InterSpeakerIntervalSlider
        function InterSpeakerIntervalSliderValueChanged(app, event)
            app.InterSpeakerIntervalEditBox.Value = app.InterSpeakerIntervalSlider.Value;
        end

        % Value changed function: InterSpeakerIntervalEditBox
        function InterSpeakerIntervalEditBoxValueChanged(app, event)
            app.InterSpeakerIntervalSlider.Value = app.InterSpeakerIntervalEditBox.Value;
        end

        % Button pushed function: MasterSwitch
        function MasterSwitchPushed(app, event)
            if app.MasterSwitch.BackgroundColor(2) == 1.00
                app.MasterSwitch.Icon = "./icons/mute.svg";
                app.MasterSwitch.BackgroundColor = [1.00,0.00,0.00];
                app.sound_out();
            else
                app.MasterSwitch.Icon = "./icons/unmute.svg";
                app.MasterSwitch.BackgroundColor = [0.00,1.00,0.00];
                app.MasterState = 1;
            end
        end

        % Value changed function: CheckBox, CheckBox_10, 
        % CheckBox_11, CheckBox_12, CheckBox_2, CheckBox_3, 
        % CheckBox_4, CheckBox_5, CheckBox_6, CheckBox_7, 
        % CheckBox_8, CheckBox_9
        function CheckBoxValueChanged(app, event)
            event.Source.Parent.findobj('Tag', 'frequency').Enable = event.Source.Value;
            event.Source.Parent.findobj('Tag', 'energy').Enable = event.Source.Value;
        end

        % Value changed function: SearchMode
        function SearchModeValueChanged(app, event)
            value = app.SearchMode.Value;
            e = {};
            if value == "Normal"
                checkboxes = app.SearchCarrierPanel.findobj('Tag', 'check');
                checkboxes(1).Value = 0;
                e.Source = checkboxes(1);
                app.CheckBoxValueChanged(e);
                for i = 2: 1: size(checkboxes, 1)
                    checkboxes(i).Value = 1;
                    e.Source = checkboxes(i);
                    app.CheckBoxValueChanged(e);
                end
                checkboxes = app.SearchModulatorPanel.findobj('Tag', 'check');
                checkboxes(1).Value = 0;
                e.Source = checkboxes(1);
                app.CheckBoxValueChanged(e);
                checkboxes(2).Value = 0;
                e.Source = checkboxes(2);
                app.CheckBoxValueChanged(e);
                for i = 3: 1: size(checkboxes, 1)
                    checkboxes(i).Value = 1;
                    e.Source = checkboxes(i);
                    app.CheckBoxValueChanged(e);
                end
                editboxes = app.SearchCyclerPanel.findobj('Tag', 'frequency');
                for i = 1: 1: size(editboxes, 1)
                    editboxes(i).Enable = 0;
                end
            elseif value == "Fixed Modulation"
                checkboxes = app.SearchCarrierPanel.findobj('Tag', 'check');
                for i = 1: 1: size(checkboxes, 1)
                    checkboxes(i).Value = 0;
                    e.Source = checkboxes(i);
                    app.CheckBoxValueChanged(e);
                end
                checkboxes = app.SearchModulatorPanel.findobj('Tag', 'check');
                checkboxes(1).Value = 1;
                e.Source = checkboxes(1);
                app.CheckBoxValueChanged(e);
                for i = 2: 1: size(checkboxes, 1)
                    checkboxes(i).Value = 0;
                    e.Source = checkboxes(i);
                    app.CheckBoxValueChanged(e);
                end
                editboxes = app.SearchCyclerPanel.findobj('Tag', 'frequency');
                for i = 1: 1: size(editboxes, 1)
                    editboxes(i).Enable = 1;
                end
            elseif value == "Fixed Carrier"
                checkboxes = app.SearchModulatorPanel.findobj('Tag', 'check');
                for i = 1: 1: size(checkboxes, 1)
                    checkboxes(i).Value = 0;
                    e.Source = checkboxes(i);
                    app.CheckBoxValueChanged(e);
                end
                checkboxes = app.SearchCarrierLayout.findobj('Tag', 'check');
                checkboxes(1).Value = 1;
                e.Source = checkboxes(1);
                app.CheckBoxValueChanged(e);
                for i = 2: 1: size(checkboxes)
                    checkboxes(i).Value = 0;
                    e.Source = checkboxes(i);
                    app.CheckBoxValueChanged(e);
                end
                editboxes = app.SearchCyclerPanel.findobj('Tag', 'frequency');
                for i = 1: 1: size(editboxes, 1)
                    editboxes(i).Enable = 1;
                end
            end
        end

        % Value changed function: WestAttenuationSlider
        function WestAttenuationSliderValueChanged(app, event)
            app.WestAttenuation.Value = app.WestAttenuationSlider.Value;
        end

        % Value changed function: EastAttenuationSlider
        function EastAttenuationSliderValueChanged(app, event)
            app.EastAttenuation.Value = app.EastAttenuationSlider.Value;
        end

        % Value changed function: EastAttenuation
        function EastAttenuationValueChanged(app, event)
            app.EastAttenuationSlider.Value = app.EastAttenuation.Value;
        end

        % Value changed function: WestAttenuation
        function WestAttenuationValueChanged(app, event)
            app.WestAttenuationSlider.Value = app.WestAttenuation.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SystemPanel = uipanel(app.GridLayout);
            app.SystemPanel.Title = 'System';
            app.GridLayout2 = uigridlayout(app.SystemPanel);
            app.Panel_5 = uipanel(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.Panel_5);
            app.InterSpeakerIntervalEditBox = uieditfield(app.GridLayout4, 'numeric');
            app.InterSpeakerIntervalEditBox.ValueChangedFcn = createCallbackFcn(app, @InterSpeakerIntervalEditBoxValueChanged, true);
            app.InterSpeakerIntervalEditBox.Value = 5;
            app.InterSpeakerIntervalmsecLabel = uilabel(app.GridLayout4);
            app.InterSpeakerIntervalmsecLabel.Text = 'Inter-Speaker Interval (msec)';
            app.Speaker = uiknob(app.GridLayout4, 'discrete');
            app.Speaker.Items = {'West', 'Biaural', 'East'};
            app.Speaker.Value = 'West';
            app.Panel = uipanel(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.InterSpeakerIntervalSlider = uislider(app.GridLayout5);
            app.InterSpeakerIntervalSlider.Limits = [-40 40];
            app.InterSpeakerIntervalSlider.MajorTicks = [-40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40];
            app.InterSpeakerIntervalSlider.ValueChangedFcn = createCallbackFcn(app, @InterSpeakerIntervalSliderValueChanged, true);
            app.InterSpeakerIntervalSlider.MinorTicks = [-40 -39 -38 -37 -36 -35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.InterSpeakerIntervalSlider.Value = 5;
            app.Panel_4 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_4);
            app.InterStimulusIntervalEditBox = uieditfield(app.GridLayout3, 'numeric');
            app.InterStimulusIntervalEditBox.ValueChangedFcn = createCallbackFcn(app, @InterStimulusIntervalEditBoxValueChanged, true);
            app.InterStimulusIntervalEditBox.Value = 1;
            app.InterStimulusIntervalsecLabel = uilabel(app.GridLayout3);
            app.InterStimulusIntervalsecLabel.Text = 'Inter-Stimulus Interval (sec)';
            app.StimulusDurationmsecEditFieldLabel = uilabel(app.GridLayout3);
            app.StimulusDurationmsecEditFieldLabel.Text = 'Stimulus Duration (msec)';
            app.StimulusDuration = uieditfield(app.GridLayout3, 'numeric');
            app.StimulusDuration.Value = 400;
            app.Panel_2 = uipanel(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.Panel_2);
            app.InterStimulusIntervalSlider = uislider(app.GridLayout6);
            app.InterStimulusIntervalSlider.Limits = [0.5 6.5];
            app.InterStimulusIntervalSlider.MajorTicks = [0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5];
            app.InterStimulusIntervalSlider.ValueChangedFcn = createCallbackFcn(app, @InterStimulusIntervalSliderValueChanged, true);
            app.InterStimulusIntervalSlider.MinorTicks = [];
            app.InterStimulusIntervalSlider.Value = 1;
            app.MasterSwitch = uibutton(app.GridLayout2, 'push');
            app.MasterSwitch.ButtonPushedFcn = createCallbackFcn(app, @MasterSwitchPushed, true);
            app.MasterSwitch.Icon = 'C:\Users\rishi\Documents\gitrepos\stimgen\icons\unmute.svg';
            app.MasterSwitch.Text = '';
            app.TDTStatusPanel = uipanel(app.GridLayout2);
            app.TDTStatusPanel.Title = 'TDT Status';
            app.GridLayout7 = uigridlayout(app.TDTStatusPanel);
            app.ConnectionLampLabel = uilabel(app.GridLayout7);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.GridLayout7);
            app.LoadCircuitLampLabel = uilabel(app.GridLayout7);
            app.LoadCircuitLampLabel.Text = 'Load Circuit';
            app.LoadCircuitLamp = uilamp(app.GridLayout7);
            app.LoadParamsLampLabel = uilabel(app.GridLayout7);
            app.LoadParamsLampLabel.Text = 'Load Params';
            app.LoadParamsLamp = uilamp(app.GridLayout7);
            app.Panel_3 = uipanel(app.GridLayout2);
            app.GridLayout8 = uigridlayout(app.Panel_3);
            app.SpeciesDropDownLabel = uilabel(app.GridLayout8);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.Species = uidropdown(app.GridLayout8);
            app.Species.Items = {'Rana pipiens', 'Hyla chrysoceles', 'Hyla versicolor', 'Psudecris feriarum'};
            app.Species.Value = 'Rana pipiens';
            app.ParadigmTabGroup = uitabgroup(app.GridLayout);
            app.SearchTab = uitab(app.ParadigmTabGroup);
            app.SearchTab.Title = 'Search';
            app.SearchTabLayout = uigridlayout(app.SearchTab);
            app.SearchCarrierPanel = uipanel(app.SearchTabLayout);
            app.SearchCarrierPanel.Title = 'Carrier';
            app.SearchCarrierLayout = uigridlayout(app.SearchCarrierPanel);
            app.Panel_14 = uipanel(app.SearchCarrierLayout);
            app.Panel_14.Tag = 'CarrierPanel';
            app.GridLayout14_7 = uigridlayout(app.Panel_14);
            app.CheckBox_6 = uicheckbox(app.GridLayout14_7);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_6.Tag = 'check';
            app.CheckBox_6.Text = '';
            app.Spinner_39 = uispinner(app.GridLayout14_7);
            app.Spinner_39.Tag = 'frequency';
            app.Spinner_39.Enable = 'off';
            app.Spinner_39.Value = 1600;
            app.Spinner_40 = uispinner(app.GridLayout14_7);
            app.Spinner_40.Tag = 'energy';
            app.Spinner_40.Enable = 'off';
            app.Spinner_40.Value = 1;
            app.Panel_13 = uipanel(app.SearchCarrierLayout);
            app.Panel_13.Tag = 'CarrierPanel';
            app.GridLayout14_6 = uigridlayout(app.Panel_13);
            app.CheckBox_5 = uicheckbox(app.GridLayout14_6);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_5.Tag = 'check';
            app.CheckBox_5.Text = '';
            app.CheckBox_5.Value = true;
            app.Spinner_37 = uispinner(app.GridLayout14_6);
            app.Spinner_37.Tag = 'frequency';
            app.Spinner_37.Value = 1600;
            app.Spinner_38 = uispinner(app.GridLayout14_6);
            app.Spinner_38.Tag = 'energy';
            app.Spinner_38.Value = 1;
            app.Panel_12 = uipanel(app.SearchCarrierLayout);
            app.Panel_12.Tag = 'CarrierPanel';
            app.GridLayout14_5 = uigridlayout(app.Panel_12);
            app.CheckBox_4 = uicheckbox(app.GridLayout14_5);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_4.Tag = 'check';
            app.CheckBox_4.Text = '';
            app.CheckBox_4.Value = true;
            app.Spinner_35 = uispinner(app.GridLayout14_5);
            app.Spinner_35.Tag = 'frequency';
            app.Spinner_35.Value = 1100;
            app.Spinner_36 = uispinner(app.GridLayout14_5);
            app.Spinner_36.Tag = 'energy';
            app.Spinner_36.Value = 1;
            app.Panel_11 = uipanel(app.SearchCarrierLayout);
            app.Panel_11.Tag = 'CarrierPanel';
            app.GridLayout14_4 = uigridlayout(app.Panel_11);
            app.CheckBox_3 = uicheckbox(app.GridLayout14_4);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_3.Tag = 'check';
            app.CheckBox_3.Text = '';
            app.CheckBox_3.Value = true;
            app.Spinner_33 = uispinner(app.GridLayout14_4);
            app.Spinner_33.Tag = 'frequency';
            app.Spinner_33.Value = 600;
            app.Spinner_34 = uispinner(app.GridLayout14_4);
            app.Spinner_34.Tag = 'energy';
            app.Spinner_34.Value = 1;
            app.Panel_10 = uipanel(app.SearchCarrierLayout);
            app.Panel_10.Tag = 'CarrierPanel';
            app.GridLayout14_3 = uigridlayout(app.Panel_10);
            app.CheckBox_2 = uicheckbox(app.GridLayout14_3);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_2.Tag = 'check';
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.Spinner_31 = uispinner(app.GridLayout14_3);
            app.Spinner_31.Tag = 'frequency';
            app.Spinner_31.Value = 300;
            app.Spinner_32 = uispinner(app.GridLayout14_3);
            app.Spinner_32.Tag = 'energy';
            app.Spinner_32.Value = 1;
            app.Panel_9 = uipanel(app.SearchCarrierLayout);
            app.Panel_9.Tag = 'CarrierPanel';
            app.GridLayout14_2 = uigridlayout(app.Panel_9);
            app.CheckBox = uicheckbox(app.GridLayout14_2);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Tag = 'check';
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.Spinner_29 = uispinner(app.GridLayout14_2);
            app.Spinner_29.Tag = 'frequency';
            app.Spinner_29.Value = 150;
            app.Spinner_30 = uispinner(app.GridLayout14_2);
            app.Spinner_30.Tag = 'energy';
            app.Spinner_30.Value = 1;
            app.Panel_8 = uipanel(app.SearchCarrierLayout);
            app.GridLayout14 = uigridlayout(app.Panel_8);
            app.FrequencyHzLabel_3 = uilabel(app.GridLayout14);
            app.FrequencyHzLabel_3.Text = 'Frequency(Hz)';
            app.EnergyLabel_3 = uilabel(app.GridLayout14);
            app.EnergyLabel_3.Text = 'Energy';
            app.SearchModePanel = uipanel(app.SearchTabLayout);
            app.SearchMode = uiknob(app.SearchModePanel, 'discrete');
            app.SearchMode.Items = {'Fixed Carrier', 'Normal', 'Fixed Modulation'};
            app.SearchMode.ValueChangedFcn = createCallbackFcn(app, @SearchModeValueChanged, true);
            app.SearchMode.Value = 'Normal';
            app.SearchCyclerPanel = uipanel(app.SearchTabLayout);
            app.SearchCyclerPanel.Title = 'Cycler';
            app.GridLayout11 = uigridlayout(app.SearchCyclerPanel);
            app.SearchStart = uispinner(app.GridLayout11);
            app.SearchStart.Step = 50;
            app.SearchStart.LowerLimitInclusive = 'off';
            app.SearchStart.Limits = [0 Inf];
            app.SearchStart.Tag = 'frequency';
            app.SearchStart.Enable = 'off';
            app.SearchStart.Value = 300;
            app.SearchStep = uispinner(app.GridLayout11);
            app.SearchStep.Step = 25;
            app.SearchStep.Limits = [0 Inf];
            app.SearchStep.Tag = 'frequency';
            app.SearchStep.Enable = 'off';
            app.SearchStep.Value = 100;
            app.SearchEnd = uispinner(app.GridLayout11);
            app.SearchEnd.Step = 50;
            app.SearchEnd.LowerLimitInclusive = 'off';
            app.SearchEnd.Limits = [0 Inf];
            app.SearchEnd.Tag = 'frequency';
            app.SearchEnd.Enable = 'off';
            app.SearchEnd.Value = 800;
            app.StartLabel = uilabel(app.GridLayout11);
            app.StartLabel.Text = 'Start';
            app.StepLabel = uilabel(app.GridLayout11);
            app.StepLabel.Text = 'Step';
            app.EndLabel = uilabel(app.GridLayout11);
            app.EndLabel.Text = 'End';
            app.SearchModulatorPanel = uipanel(app.SearchTabLayout);
            app.SearchModulatorPanel.Title = 'Modulator';
            app.SearchModulatorLayout = uigridlayout(app.SearchModulatorPanel);
            app.Panel_21 = uipanel(app.SearchModulatorLayout);
            app.Panel_21.Tag = 'ModulatorPanel';
            app.GridLayout14_14 = uigridlayout(app.Panel_21);
            app.CheckBox_12 = uicheckbox(app.GridLayout14_14);
            app.CheckBox_12.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_12.Tag = 'check';
            app.CheckBox_12.Text = '';
            app.Spinner_51 = uispinner(app.GridLayout14_14);
            app.Spinner_51.Tag = 'frequency';
            app.Spinner_51.Enable = 'off';
            app.Spinner_51.Value = 80;
            app.Spinner_52 = uispinner(app.GridLayout14_14);
            app.Spinner_52.Tag = 'energy';
            app.Spinner_52.Enable = 'off';
            app.Spinner_52.Value = 1;
            app.Panel_20 = uipanel(app.SearchModulatorLayout);
            app.Panel_20.Tag = 'ModulatorPanel';
            app.GridLayout14_13 = uigridlayout(app.Panel_20);
            app.CheckBox_11 = uicheckbox(app.GridLayout14_13);
            app.CheckBox_11.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_11.Tag = 'check';
            app.CheckBox_11.Text = '';
            app.Spinner_49 = uispinner(app.GridLayout14_13);
            app.Spinner_49.Tag = 'frequency';
            app.Spinner_49.Enable = 'off';
            app.Spinner_49.Value = 80;
            app.Spinner_50 = uispinner(app.GridLayout14_13);
            app.Spinner_50.Tag = 'energy';
            app.Spinner_50.Enable = 'off';
            app.Spinner_50.Value = 1;
            app.Panel_19 = uipanel(app.SearchModulatorLayout);
            app.Panel_19.Tag = 'ModulatorPanel';
            app.GridLayout14_12 = uigridlayout(app.Panel_19);
            app.CheckBox_10 = uicheckbox(app.GridLayout14_12);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_10.Tag = 'check';
            app.CheckBox_10.Text = '';
            app.CheckBox_10.Value = true;
            app.Spinner_47 = uispinner(app.GridLayout14_12);
            app.Spinner_47.Tag = 'frequency';
            app.Spinner_47.Value = 60;
            app.Spinner_48 = uispinner(app.GridLayout14_12);
            app.Spinner_48.Tag = 'energy';
            app.Spinner_48.Value = 1;
            app.Panel_18 = uipanel(app.SearchModulatorLayout);
            app.Panel_18.Tag = 'ModulatorPanel';
            app.GridLayout14_11 = uigridlayout(app.Panel_18);
            app.CheckBox_9 = uicheckbox(app.GridLayout14_11);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_9.Tag = 'check';
            app.CheckBox_9.Text = '';
            app.CheckBox_9.Value = true;
            app.Spinner_45 = uispinner(app.GridLayout14_11);
            app.Spinner_45.Tag = 'frequency';
            app.Spinner_45.Value = 40;
            app.Spinner_46 = uispinner(app.GridLayout14_11);
            app.Spinner_46.Tag = 'energy';
            app.Spinner_46.Value = 1;
            app.Panel_17 = uipanel(app.SearchModulatorLayout);
            app.Panel_17.Tag = 'ModulatorPanel';
            app.GridLayout14_10 = uigridlayout(app.Panel_17);
            app.CheckBox_8 = uicheckbox(app.GridLayout14_10);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_8.Tag = 'check';
            app.CheckBox_8.Text = '';
            app.CheckBox_8.Value = true;
            app.Spinner_43 = uispinner(app.GridLayout14_10);
            app.Spinner_43.Tag = 'frequency';
            app.Spinner_43.Value = 20;
            app.Spinner_44 = uispinner(app.GridLayout14_10);
            app.Spinner_44.Tag = 'energy';
            app.Spinner_44.Value = 1;
            app.Panel_16 = uipanel(app.SearchModulatorLayout);
            app.Panel_16.Tag = 'ModulatorPanel';
            app.GridLayout14_9 = uigridlayout(app.Panel_16);
            app.CheckBox_7 = uicheckbox(app.GridLayout14_9);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_7.Tag = 'check';
            app.CheckBox_7.Text = '';
            app.CheckBox_7.Value = true;
            app.Spinner_41 = uispinner(app.GridLayout14_9);
            app.Spinner_41.Tag = 'frequency';
            app.Spinner_41.Value = 10;
            app.Spinner_42 = uispinner(app.GridLayout14_9);
            app.Spinner_42.Tag = 'energy';
            app.Spinner_42.Value = 1;
            app.Panel_15 = uipanel(app.SearchModulatorLayout);
            app.GridLayout14_8 = uigridlayout(app.Panel_15);
            app.FrequencyHzLabel_4 = uilabel(app.GridLayout14_8);
            app.FrequencyHzLabel_4.Text = 'Frequency(Hz)';
            app.EnergyLabel_4 = uilabel(app.GridLayout14_8);
            app.EnergyLabel_4.Text = 'Energy';
            app.Tab2 = uitab(app.ParadigmTabGroup);
            app.Tab2.Title = 'Tab2';
            app.Panel_7 = uipanel(app.GridLayout);
            app.GridLayout12 = uigridlayout(app.Panel_7);
            app.EastStimulusPlot = uiaxes(app.GridLayout12);
            title(app.EastStimulusPlot, 'East Stimulus')
            xlabel(app.EastStimulusPlot, 'X')
            ylabel(app.EastStimulusPlot, 'Y')
            zlabel(app.EastStimulusPlot, 'Z')
            app.EastModulatorPlot = uiaxes(app.GridLayout12);
            title(app.EastModulatorPlot, 'East Modulator')
            xlabel(app.EastModulatorPlot, 'X')
            ylabel(app.EastModulatorPlot, 'Y')
            zlabel(app.EastModulatorPlot, 'Z')
            app.EastCarrierPlot = uiaxes(app.GridLayout12);
            title(app.EastCarrierPlot, 'East Carrier')
            xlabel(app.EastCarrierPlot, 'X')
            ylabel(app.EastCarrierPlot, 'Y')
            zlabel(app.EastCarrierPlot, 'Z')
            app.WestStimulusPlot = uiaxes(app.GridLayout12);
            title(app.WestStimulusPlot, 'West Stimulus')
            xlabel(app.WestStimulusPlot, 'time (sec)')
            ylabel(app.WestStimulusPlot, 'Amplitude (V)')
            zlabel(app.WestStimulusPlot, 'Z')
            app.WestModulatorPlot = uiaxes(app.GridLayout12);
            title(app.WestModulatorPlot, 'West Modulator')
            xlabel(app.WestModulatorPlot, 'time (sec)')
            ylabel(app.WestModulatorPlot, 'Amplitude (V)')
            zlabel(app.WestModulatorPlot, 'Z')
            app.WestCarrierPlot = uiaxes(app.GridLayout12);
            title(app.WestCarrierPlot, 'West Carrier')
            xlabel(app.WestCarrierPlot, 'time (sec)')
            ylabel(app.WestCarrierPlot, 'Amplitude (V)')
            zlabel(app.WestCarrierPlot, 'Z')
            app.EastAttenuationSlider = uislider(app.GridLayout12);
            app.EastAttenuationSlider.Limits = [-72 -6];
            app.EastAttenuationSlider.MajorTicks = [-72 -69 -66 -63 -60 -57 -54 -51 -48 -45 -42 -39 -36 -33 -30 -27 -24 -21 -18 -15 -12 -9 -6 -3 0];
            app.EastAttenuationSlider.ValueChangedFcn = createCallbackFcn(app, @EastAttenuationSliderValueChanged, true);
            app.EastAttenuationSlider.Value = -27;
            app.WestAttenuationSlider = uislider(app.GridLayout12);
            app.WestAttenuationSlider.Limits = [-72 -6];
            app.WestAttenuationSlider.MajorTicks = [-72 -69 -66 -63 -60 -57 -54 -51 -48 -45 -42 -39 -36 -33 -30 -27 -24 -21 -18 -15 -12 -9 -6 -3 0];
            app.WestAttenuationSlider.ValueChangedFcn = createCallbackFcn(app, @WestAttenuationSliderValueChanged, true);
            app.WestAttenuationSlider.Value = -27;
            app.WestattndBLabel = uilabel(app.GridLayout12);
            app.WestattndBLabel.Text = 'West (attn dB)';
            app.EastattndBLabel = uilabel(app.GridLayout12);
            app.EastattndBLabel.Text = 'East (attn dB)';
            app.WestAttenuation = uispinner(app.GridLayout12);
            app.WestAttenuation.Step = 3;
            app.WestAttenuation.Limits = [-72 -6];
            app.WestAttenuation.ValueChangedFcn = createCallbackFcn(app, @WestAttenuationValueChanged, true);
            app.WestAttenuation.Value = -27;
            app.EastAttenuation = uispinner(app.GridLayout12);
            app.EastAttenuation.Step = 3;
            app.EastAttenuation.Limits = [-72 -6];
            app.EastAttenuation.ValueChangedFcn = createCallbackFcn(app, @EastAttenuationValueChanged, true);
            app.EastAttenuation.Value = -27;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stimulusV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end